classdef adjustMontageIdxs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        adjustidxsTab       matlab.ui.container.Tab
        DataPanel           matlab.ui.container.Panel
        forward             matlab.ui.control.Button
        back                matlab.ui.control.Button
        zoomedoutviewLabel  matlab.ui.control.Label
        zoomoutButton       matlab.ui.control.Button
        saveButton          matlab.ui.control.Button
        Tab2                matlab.ui.container.Tab
    end

    
    properties (Access = public)
        dirname % Description
        montageDataRaw
        montageData
        powerTable
        outdatcompleteAcc
        outRec
        outdatcomplete
        hsb
        ets
        ete
        hCurLine % handle to current line
        hCurLineStatus % handle to current line stuats
        hpltStart
        hpltEnd
        xlimsorig
        firstzoom % is it the first zoom
        montageidx
        firstsec
        secsraw
        montageDataOut
    end
    
    methods (Access = public)
        
        function LineDown(app,event,src)
            %             fprintf('line down cur line status %d\n',app.hCurLineStatus);
            app.hCurLine = event;
            app.hCurLineStatus = 1;
        end
        
        function MouseMove(app,event,src)
            if app.hCurLineStatus
                %                 fprintf('mouse move cur line status %d\n',app.hCurLineStatus);
                hUiaxes = get(app.hCurLine,'Parent');
                cp = get ( hUiaxes, 'CurrentPoint' );
                set ( app.hCurLine, 'XData', [cp(1,1) cp(1,1)] );
                idx = app.hCurLine.UserData.idx;
                switch app.hCurLine.UserData.type
                    case 'start'
                        hlines = app.hpltStart(idx,:);
                        for h = 1:length(hlines)
                            set ( hlines(h), 'XData', [cp(1,1) cp(1,1)] );
                        end
                    case 'stop'
                        hlines = app.hpltEnd(idx,:);
                        for h = 1:length(hlines)
                            set ( hlines(h), 'XData', [cp(1,1) cp(1,1)] );
                        end
                end
            end
        end
        
        function MouseUp(app,event,src)
            %             fprintf('mouse up cur line status %d\n',app.hCurLineStatus);
            app.hCurLineStatus = 0;
            app.adjustZoom();
        end
        
        function updateTitles(app)
            for i = 1:4
                idx = app.montageidx;
                fn = sprintf('chan%d',i);
                ttl = app.montageDataRaw.(fn){idx};
                app.hsb(i).Title.String = ttl;
            end
            
        end
        
        function adjustZoom(app)
            % zoom into the max in the current view
            idx = app.montageidx;
            xlim1 = app.hpltStart(idx,1).XData(1);
            xlim2 = app.hpltEnd(idx,1).XData(1);
            
            idxuse = app.secsraw >= xlim1 & app.secsraw <= xlim2;
            for i = 1:4
                fn = sprintf('key%d',(i-1));
                y = app.outdatcomplete.(fn);
                y = y(idxuse);
                ylims = [min(y) max(y)];
                if ylims(2) > ylims(1)
                    app.hsb(i).YLim = ylims;
                end
            end
        end
    end
    
    methods (Access = private)
        
        function results = func5(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dirname)
            app.dirname = dirname;
            % register callback move lines
            set(app.UIFigure,'WindowButtonMotion',@app.MouseMove);
            set(app.UIFigure,'WindowButtonUpFcn',@app.MouseUp);
            
            app.firstzoom = 1; % its the first time you are doing a zoom in
            app.montageidx = 1;
            % extract the montage
            [app.montageData, app.montageDataRaw] = extract_montage_data(app.dirname);
            [app.outdatcomplete,app.outRec,eventTable,app.outdatcompleteAcc,app.powerTable] =  MAIN_load_rcs_data_from_folder(app.dirname);
            eventTable = allign_events_time_domain_time(eventTable,app.outdatcomplete);
            addpath(genpath(fullfile(pwd,'toolboxes','panel-2.14')));
            
            % raw data
            ncol = 1;
            nrow = 4;
            
            % move to scalar
            secs = app.outdatcomplete.derivedTimes;
            app.firstsec = secs(1);
            secs = seconds(secs - app.firstsec);
            app.secsraw = secs;
            app.xlimsorig = [min(secs) max(secs)];
            deltaUse = 5;
            
            eventTable.insTimes = seconds(eventTable.insTimes - app.firstsec);
            app.hsb = gobjects(4,1);
            for i = 1:4
                app.hsb(i,1) = subplot(nrow,ncol,i,'Parent',app.DataPanel);
                hold(app.hsb(i,1),'on');
                fn = sprintf('key%d',i-1);
                y = app.outdatcomplete.(fn);
                plot(secs,y,'LineWidth',0.5,'Color',[0 0 0.8],'Parent',app.hsb(i,1));
            end
            % plot event table lines
            % insert event table markers and link them
            % find start events
            idxStart = cellfun(@(x) any(strfind(x,'Start')),eventTable.EventType);
            idxEnd = cellfun(@(x) any(strfind(x,'Stop')),eventTable.EventType);
            
            app.ets = eventTable(idxStart,:);
            app.ete = eventTable(idxEnd,:);
            app.hpltStart = gobjects(sum(idxStart),4);
            app.hpltEnd = gobjects(sum(idxStart),4);
            % plot the lines for event markers
            for i = 1:size(app.montageDataRaw,1)
                for c = 1:4
                    hsb = get(app.hsb(c));
                    ylims = hsb.YLim;
                    % start
                    % using events
%                     xval = app.ets.insTimes(i) +  deltaUse;
                    % using montage device settings
                    xval = seconds( (app.montageDataRaw.derivedTimesRaw{i}(1) - app.firstsec) ) + deltaUse;
                    
                    startTime = xval ;% XXX
                    hplt = plot([xval xval],ylims,'Parent',app.hsb(c),'Color',[0 0.8 0 0.7],'LineWidth',3);
                    meta.type = 'start';
                    meta.idx = i;
                    hplt.UserData = meta;
                    app.hpltStart(i,c) = hplt;
                    hplt.ButtonDownFcn = @app.LineDown;
                    % end
%                     xval = app.ete.insTimes(i) - deltaUse;
                    % using montage device settings
                    xval = seconds( (app.montageDataRaw.derivedTimesRaw{i}(end) - app.firstsec) ) - deltaUse;
                    endTime = xval;
                    hplt = plot([xval xval],ylims,'Parent',app.hsb(c),'Color',[0.8 0 0 0.7],'LineWidth',3);
                    meta.type = 'stop';
                    meta.idx = i;
                    hplt.UserData = meta;
                    app.hpltEnd(i,c) = hplt;
                    hplt.ButtonDownFcn = @app.LineDown;
                end
            end
            linkaxes(app.hsb,'x');
            %
            
        end

        % Button pushed function: forward
        function forwardButtonPushed(app, event)
            if app.firstzoom
                app.firstzoom = 0;
            else
                maxsize = size(app.montageDataRaw.derivedTimesRaw,1);
                if app.montageidx == maxsize
                    app.montageidx = maxsize;
                else
                    app.montageidx = app.montageidx + 1;
                end
            end
            idx = app.montageidx;
            xlimsraw = [app.montageDataRaw.derivedTimesRaw{idx}(1) app.montageDataRaw.derivedTimesRaw{idx}(end)];
            xlims = seconds(xlimsraw - app.firstsec); % move into scalar
            app.hsb(1).XLim = xlims;
            app.zoomedoutviewLabel.Text = sprintf('montage %d',idx);
            app.updateTitles();
            app.adjustZoom();
        end

        % Button pushed function: zoomoutButton
        function zoomoutButtonPushed(app, event)
            app.hsb(1).XLim = app.xlimsorig;
            app.zoomedoutviewLabel.Text = 'zoom out view';
            for i = 1:4
                ttl = sprintf('chan %d',i);
                app.hsb(i).Title.String = ttl;
            end
            
        end

        % Button pushed function: back
        function backButtonPushed(app, event)
            if app.firstzoom
                app.firstzoom = 0;
            else
                if app.montageidx == 1
                    app.montageidx = 1;
                else
                    app.montageidx = app.montageidx - 1;
                end
            end
            idx = app.montageidx;
            xlimsraw = [app.montageDataRaw.derivedTimesRaw{idx}(1) app.montageDataRaw.derivedTimesRaw{idx}(end)];
            xlims = seconds(xlimsraw - app.firstsec); % move into scalar
            app.hsb(1).XLim = xlims;
            app.zoomedoutviewLabel.Text = sprintf('montage %d',idx);
            app.updateTitles();
            app.adjustZoom();
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            % save montage data raw
            app.montageDataOut = app.montageDataRaw;
            for i = 1:size(app.montageDataOut,1)
                xlim1 = app.hpltStart(i,1).XData(1);
                xlim2 = app.hpltEnd(i,1).XData(1);
                idxuse = app.secsraw >=xlim1 & app.secsraw <=xlim2;
                secsraw = app.secsraw(idxuse);
                % get data
                yTemp = [];
                for c = 1:4
                    cfnm = sprintf('key%d',c-1);
                    yTemp(:,c) = app.outdatcomplete.(cfnm)(idxuse)';
                end
                app.montageDataOut.data{i} = {};
                app.montageDataOut.data{i} = yTemp;
                app.montageDataOut.derivedTimesRaw{i}  = secsraw;
                app.montageDataOut.derivedTimes{i} = secsraw - secsraw(1);
                app.montageDataOut.duration(i) = seconds(secsraw(end) - secsraw(1));
                clear('yTemp');
            end
            app.hsb();
            filenamesave = fullfile(app.dirname,'rawMontageData.mat');
            montagDataRawManualIdxs = app.montageDataOut;
            montageData = app.montageData;
            montageDataRaw = app.montageDataRaw
            if exist(filenamesave','file')
                save(filenamesave,'montagDataRawManualIdxs','-append');
            else
                save(filenamesave,'montageData','montageDataRaw','montagDataRawManualIdxs');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.adjustidxsTab = uitab(app.TabGroup);
            app.adjustidxsTab.Title = 'adjust idxs';
            app.DataPanel = uipanel(app.adjustidxsTab);
            app.DataPanel.Title = 'Data';
            app.forward = uibutton(app.adjustidxsTab, 'push');
            app.forward.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forward.Text = '>>';
            app.back = uibutton(app.adjustidxsTab, 'push');
            app.back.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.back.Text = '<<';
            app.zoomedoutviewLabel = uilabel(app.adjustidxsTab);
            app.zoomedoutviewLabel.Text = 'zoomed out view';
            app.zoomoutButton = uibutton(app.adjustidxsTab, 'push');
            app.zoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @zoomoutButtonPushed, true);
            app.zoomoutButton.Text = 'zoom out';
            app.saveButton = uibutton(app.adjustidxsTab, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adjustMontageIdxs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end