classdef allignRCS_Delsys_Data_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        RCSPanel                matlab.ui.container.Panel
        RedrawButton            matlab.ui.control.Button
        FindPeaksButton         matlab.ui.control.Button
        channelDropDownLabel    matlab.ui.control.Label
        rcsChannelDropDown      matlab.ui.control.DropDown
        DelsysPanel             matlab.ui.container.Panel
        RedrawButton_2          matlab.ui.control.Button
        DelsysFindPeaks         matlab.ui.control.Button
        channelDropDown_2Label  matlab.ui.control.Label
        delsysChannelDropDown   matlab.ui.control.DropDown
        CenterPanel             matlab.ui.container.Panel
        UIAxes_1                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
        ComputePanel            matlab.ui.container.Panel
        ComputeButton           matlab.ui.control.Button
        checkButton             matlab.ui.control.Button
        DelsysAccDropDownLabel  matlab.ui.control.Label
        DelsysAccDropDown       matlab.ui.control.DropDown
        RCSSRLabel              matlab.ui.control.Label
        RCSsrOUT                matlab.ui.control.Label
        saveButton              matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        hCurLine % handle to current line
        hCurLineStatus % handle to current line stuats
        outdatcomplete
        outRec
        eventTable
        outdatcompleteAcc
        powerTable
        dataraw
        scatterDelsys % handles to scatter plots
        scatterRcs
        rcsMinPeakDistnaceInDataPoints % min peak distance in data point for RC+S data
        rcsSrate
        delsysSrate5HzSignal % Description
        rcsComputedSr % Description
        rcsPointsOut  % rcs points that are the same as delsys points
        delsysPointsOut  % delsys points that are the same as rcs points
        rcsFolder % folder where RC+S data exists
        delsysMatFileName % delsys mat file name
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
        
        function results = func3(app)
            
        end
    end
    
    methods (Access = public)
        
        function LineDown(app,event,src)
            fprintf('line down cur line status %d\n',app.hCurLineStatus);
            app.hCurLine = event;
            app.hCurLineStatus = 1;
        end
        
        function MouseMove(app,event,src)
            if app.hCurLineStatus
                fprintf('mouse move cur line status %d\n',app.hCurLineStatus);
                hUiaxes = get(app.hCurLine,'Parent');
                cp = get ( hUiaxes, 'CurrentPoint' );
                set ( app.hCurLine, 'YData', [cp(1,2) cp(1,2)] );
                hUiaxes.UserData.threshold = cp(1,2);
            end
        end
        
        function MouseUp(app,event,src)
            fprintf('mouse up cur line status %d\n',app.hCurLineStatus);
            app.hCurLineStatus = 0;
        end
        
        function ScatterPressed(app,event,src)
            if ~event.UserData
                event.MarkerFaceColor = 'b';
                event.UserData = 1;
            elseif event.UserData
                event.MarkerFaceColor = 'none';
                event.UserData = 0;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, rcsfolder, delsysmatfilename)
            [app.outdatcomplete,app.outRec,app.eventTable,app.outdatcompleteAcc,app.powerTable] =  ...
                MAIN_load_rcs_data_from_folder(rcsfolder);
            
            %% set up rcs
            rcsChannelNames = fieldnames(app.outdatcomplete);
            idxuse = cellfun(@(x) any(strfind(x,'key')), rcsChannelNames);
            srateRcs = unique(app.outdatcomplete.samplerate);
            app.rcsSrate = srateRcs;
            app.rcsMinPeakDistnaceInDataPoints =  ceil(srateRcs*0.15); % assuming that you did 5Hz sync pulse, this would be a little under in data points
            % set up drop down channel
            app.rcsChannelDropDown.Items = rcsChannelNames(idxuse);
            t = app.outdatcomplete.derivedTimes - app.outdatcomplete.derivedTimes(1); % so unit is seconds
            tSecs = seconds(t);
            y = app.outdatcomplete.key3;
            
            hplt = plot(app.UIAxes_1,tSecs, y);
            % set up name
            idxchan = 4;
            ttlnameuse = sprintf('RC+S %s %s',app.outRec(idxchan).tdData(1).chanFullStr);
            title(app.UIAxes_1,ttlnameuse)
            
            % setup threshold
            hold(app.UIAxes_1,'on');
            threshX = get(app.UIAxes_1,'XLim');
            ylims   = get(app.UIAxes_1,'YLim');
            yuse = ylims(2)/2;
            hplt = plot(app.UIAxes_1,threshX,[yuse yuse]);
            hplt.ButtonDownFcn = @app.LineDown;
            hplt.LineWidth = 5;
            hplt.Color = [0.8 0 0 0.5];
            
            app.UIAxes_1.UserData.x = tSecs;
            app.UIAxes_1.UserData.y = y;
            app.UIAxes_1.UserData.threshold = yuse;
            %% end setup rcs
            
            
            %% setup delsys
            app.dataraw = load(delsysmatfilename);
            app.dataraw = app.dataraw.dataraw;
            delsysChannelFieldNames = fieldnames(app.dataraw);
            idxBestGuess = cellfun(@(x) any(strfind(lower(x),'dbs')), delsysChannelFieldNames) & ...
                cellfun(@(x) any(strfind(lower(x),'5hz')), delsysChannelFieldNames) & ...
                cellfun(@(x) any(strfind(lower(x),'emg')), delsysChannelFieldNames) ;
            if sum(idxBestGuess)  == 1
                chanFnUse = delsysChannelFieldNames{idxBestGuess};
            else
                idxUse = find(cellfun(@(x) any(strfind(lower(x),'emg')), delsysChannelFieldNames)==1) ;
                chanFnUse = delsysChannelFieldNames{idxUse(1)};
            end
            
            % set up drop down channel controlling graph
            idxDropDown = ~cellfun(@(x) any(strfind(lower(x),'srates')), delsysChannelFieldNames);
            app.delsysChannelDropDown.Items = delsysChannelFieldNames(idxDropDown);
            y = app.dataraw.(chanFnUse);
            if any(strfind(lower(chanFnUse),'emg'))
                srateDelsys = app.dataraw.srates.EMG;
            elseif any(strfind(lower(chanFnUse),'mag'))
                srateDelsys = app.dataraw.srates.Mag;
            elseif any(strfind(lower(chanFnUse),'gyro'))
                srateDelsys = app.dataraw.srates.Gyro;
            elseif any(strfind(lower(chanFnUse),'acc'))
                srateDelsys = app.dataraw.srates.ACC;
            elseif any(strfind(lower(chanFnUse),'trig'))
                srateDelsys = app.dataraw.srates.trig;
            end
            app.delsysSrate5HzSignal = srateDelsys;
            temp = (0:1:length(y)-1)./srateDelsys;
            tSecs = temp;
            hplt = plot(app.UIAxes_2,tSecs, y);
            % delsys fn
            chanFnUseTitle = strrep(chanFnUse ,'_',' ');
            ttlnameuse = sprintf('Delsys %s',chanFnUseTitle);
            title(app.UIAxes_2,ttlnameuse);
            % set up thresh
            hold(app.UIAxes_2,'on');
            threshX = get(app.UIAxes_2,'XLim');
            ylims   = get(app.UIAxes_2,'YLim');
            yuse = ylims(2)/2;
            hplt = plot(app.UIAxes_2,threshX,[yuse yuse]);
            hplt.ButtonDownFcn = @app.LineDown;
            hplt.LineWidth = 5;
            hplt.Color = [0 0 0.8 0.5];
            
            app.UIAxes_2.UserData.x = tSecs;
            app.UIAxes_2.UserData.y = y;
            app.UIAxes_2.UserData.threshold = yuse;
            
            %% setup compute panel
            idxBestGuess = cellfun(@(x) any(strfind(lower(x),'dbs')), delsysChannelFieldNames) & ...
                cellfun(@(x) any(strfind(lower(x),'5hz')), delsysChannelFieldNames) & ...
                cellfun(@(x) any(strfind(lower(x),'acc')), delsysChannelFieldNames) ;
            if sum(idxBestGuess)  == 1
                chanFnUse = delsysChannelFieldNames{idxBestGuess};
            else
                idxUse = find(cellfun(@(x) any(strfind(lower(x),'acc')), delsysChannelFieldNames)==1) ;
                chanFnUse = delsysChannelFieldNames{idxUse(1)};
            end
            idxDropDown = ~cellfun(@(x) any(strfind(lower(x),'srates')), delsysChannelFieldNames);
            app.DelsysAccDropDown.Items = delsysChannelFieldNames(idxDropDown);
            app.DelsysAccDropDown.Value = chanFnUse;
            
            app.rcsFolder = rcsfolder;
            app.delsysMatFileName = delsysmatfilename;
            set(app.UIFigure,'WindowButtonMotion',@app.MouseMove);
            set(app.UIFigure,'WindowButtonUpFcn',@app.MouseUp);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {252, '1x', 135};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: RedrawButton
        function RedrawButtonPushed(app, event)
    
        end

        % Value changed function: rcsChannelDropDown
        function rcsChannelDropDownValueChanged(app, event)
            value = app.rcsChannelDropDown.Value;
            idxchan = find(strcmp(value,app.rcsChannelDropDown.Items)==1);
            % this for rcs
            % set up drop down channel
            cla(app.UIAxes_1);
            
            t = app.outdatcomplete.derivedTimes - app.outdatcomplete.derivedTimes(1);
            tSecs = seconds(t);
            y = app.outdatcomplete.(value);
            hplt = plot(app.UIAxes_1,tSecs, y);
            ttlnameuse = sprintf('RC+S %s %s',app.outRec.tdData(idxchan).chanFullStr);
            title(app.UIAxes_1,ttlnameuse)
            % setup threshold
            hold(app.UIAxes_1,'on');
            threshX = get(app.UIAxes_1,'XLim');
            ylims   = get(app.UIAxes_1,'YLim');
            yuse = ylims(2)/2;
            hplt = plot(app.UIAxes_1,threshX,[yuse yuse]);
            hplt.ButtonDownFcn = @app.LineDown;
            hplt.LineWidth = 5;
            hplt.Color = [0.8 0 0 0.5];
            
            app.UIAxes_1.UserData.x = tSecs;
            app.UIAxes_1.UserData.y = y;
            app.UIAxes_1.UserData.threshold = yuse;
            
            %% end setup rcs
        end

        % Value changed function: delsysChannelDropDown
        function delsysChannelDropDownValueChanged(app, event)
            % delsys channel hcange callback
            value = app.delsysChannelDropDown.Value;
            idxchan = find(strcmp(value,app.delsysChannelDropDown.Items)==1);
            
            %% setup delsys
            cla(app.UIAxes_2);
            delsysChannelFieldNames = app.delsysChannelDropDown.Items;
            chanFnUse = delsysChannelFieldNames{idxchan};
            % delsys fn
            chanFnUseTitle = strrep(chanFnUse ,'_',' ');
            ttlnameuse = sprintf('Delsys %s',chanFnUseTitle);
            title(app.UIAxes_2,ttlnameuse);
            
            y = app.dataraw.(chanFnUse);
            if any(strfind(lower(chanFnUse),'emg'))
                srateDelsys = app.dataraw.srates.EMG;
            elseif any(strfind(lower(chanFnUse),'mag'))
                srateDelsys = app.dataraw.srates.Mag;
            elseif any(strfind(lower(chanFnUse),'gyro'))
                srateDelsys = app.dataraw.srates.Gyro;
            elseif any(strfind(lower(chanFnUse),'acc'))
                srateDelsys = app.dataraw.srates.ACC;
            elseif any(strfind(lower(chanFnUse),'trig'))
                srateDelsys = app.dataraw.srates.trig;
            end
            app.delsysSrate5HzSignal = srateDelsys;
            temp = (0:1:length(y)-1)./srateDelsys;
            tSecs = temp;
            hplt = plot(app.UIAxes_2,tSecs, y);
            % set up thresh
            hold(app.UIAxes_2,'on');
            threshX = get(app.UIAxes_2,'XLim');
            ylims   = get(app.UIAxes_2,'YLim');
            yuse = ylims(2)/2;
            hplt = plot(app.UIAxes_2,threshX,[yuse yuse]);
            hplt.ButtonDownFcn = @app.LineDown;
            hplt.LineWidth = 5;
            hplt.Color = [0 0 0.8 0.5];
            % store the data in the axes user data
            app.UIAxes_2.UserData.x = tSecs;
            app.UIAxes_2.UserData.y = y;
            app.UIAxes_2.UserData.threshold = yuse;
            
        end

        % Button pushed function: DelsysFindPeaks
        function DelsysFindPeaksButtonPushed(app, event)
            % get idxs to use as well as fine the peaks in this case
            datause = app.UIAxes_2.UserData.y;
            secs    = app.UIAxes_2.UserData.x;
            xlimsuse = app.UIAxes_2.XLim;
            thresh   = app.UIAxes_2.UserData.threshold;
            idxuse = secs > xlimsuse(1) & secs < xlimsuse(2);
            % bug in find peaks in that it won't work with datetime arrays
            % for the x axis so have to move things back and forth
            [pksuse,locuse,~,~] = findpeaks(datause(idxuse),secs(idxuse), ...
                'MinPeakHeight',thresh,'MinPeakDistance',0.15);
            
            for s = 1:length(app.scatterDelsys)
                delete(app.scatterDelsys(s));
            end
            
            app.scatterDelsys = [];
            hold on;
            for s = 1:length(pksuse)
                hscat(s) = ...
                    scatter(app.UIAxes_2,...
                    locuse(s),pksuse(s),...
                    400,'r',...
                    'UserData',1,...
                    'ButtonDownFcn',@app.ScatterPressed,...
                    'UserData',0);
            end
            app.scatterDelsys = hscat;
        end

        % Button pushed function: FindPeaksButton
        function FindPeaksButtonPushed(app, event)
            % get idxs to use as well as fine the peaks in this case
            datause = app.UIAxes_1.UserData.y;
            secs    = app.UIAxes_1.UserData.x;
            xlimsuse = app.UIAxes_1.XLim;
            thresh   = app.UIAxes_1.UserData.threshold;
            idxuse = secs > xlimsuse(1) & secs < xlimsuse(2);
            % bug in find peaks in that it won't work with datetime arrays
            % for the x axis so have to move things back and forth
            [pksuse,loctemp,~,~] = findpeaks(datause(idxuse), ...
                'MinPeakHeight',thresh,'MinPeakDistance',app.rcsMinPeakDistnaceInDataPoints);
            temp = secs(idxuse);
            locuse = temp(loctemp);
            
            for s = 1:length(app.scatterRcs)
                delete(app.scatterRcs(s));
            end
            
            app.scatterRcs = [];
            hold on;
            for s = 1:length(pksuse)
                hscat(s) = ...
                    scatter(app.UIAxes_1,...
                    locuse(s),pksuse(s),...
                    400,'r',...
                    'UserData',1,...
                    'ButtonDownFcn',@app.ScatterPressed,...
                    'UserData',0);
            end
            app.scatterRcs = hscat;
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % get delsys differnces
            cnt = 1;
            for s = 1:length(app.scatterDelsys)
                if app.scatterDelsys(s).UserData
                    delsyspoint(cnt) = app.scatterDelsys(s).XData; % data is no longer in seconds
                    cnt = cnt + 1;
                end
            end
            % get rcs differences
            cnt = 1;
            for s = 1:length(app.scatterRcs)
                if app.scatterRcs(s).UserData
                    rcspoints(cnt) = app.scatterRcs(s).XData; % data is in seconds
                    cnt = cnt + 1;
                end
            end
            app.rcsPointsOut = rcspoints;
            app.delsysPointsOut = delsyspoint;
            
            rcspoints = rcspoints.* app.rcsSrate;
            delsyspoint = delsyspoint.* app.delsysSrate5HzSignal;
            
            Diffrcs = rcspoints(2)- rcspoints(1); % XXX just use first point consider changing
            Diffdelsys = delsyspoint(2)- delsyspoint(1);
            rcsComputedSr  = (app.delsysSrate5HzSignal * Diffrcs ) / Diffdelsys;
            rcsComputedSr = round(rcsComputedSr);
            app.RCSsrOUT.Text = sprintf('%dHz',rcsComputedSr);
            app.rcsComputedSr = rcsComputedSr;
            
            
        end

        % Button pushed function: checkButton
        function checkButtonPushed(app, event)
            strSec = app.DelsysAccDropDown.Value;
            idxFirstUnderscore = strfind(strSec,'_');
            delsysChannelFieldNames = fieldnames(app.dataraw);
            idxUse = cellfun(@(x) any(strfind(lower(x),lower(strSec(1:idxFirstUnderscore(1))))), delsysChannelFieldNames) & ...
                cellfun(@(x) any(strfind(lower(x),'acc')), delsysChannelFieldNames) & ...
                ~cellfun(@(x) any(strfind(lower(x),'srate')), delsysChannelFieldNames) ;
            delsysFn = delsysChannelFieldNames(idxUse);
            
            % plot delsys acc
            hfig = figure;
            haxDelAcc = subplot(2,1,1);
            haxRcsAcc = subplot(2,1,2);
            
            axes(haxDelAcc);
            hold on;
            xx = app.dataraw.(delsysFn{1});
            yy = app.dataraw.(delsysFn{2});
            zz = app.dataraw.(delsysFn{3});
            secs = ((0:1:length(xx )-1 )./app.dataraw.srates.ACC) -app.delsysPointsOut(1);
            plot(haxDelAcc,secs',xx-mean(xx),'LineWidth',2);
            plot(haxDelAcc,secs',yy-mean(yy),'LineWidth',2);
            plot(haxDelAcc,secs',zz-mean(zz),'LineWidth',2);
            title('delsys acc');
            ylimsDelsysAcc = get(haxDelAcc,'YLim');
            
            % plot rcs acc
            axes(haxRcsAcc);
            hold on;
            xx = app.outdatcompleteAcc.XSamples;
            yy = app.outdatcompleteAcc.YSamples;
            zz = app.outdatcompleteAcc.ZSamples;
            secs = seconds(app.outdatcompleteAcc.derivedTimes - app.outdatcompleteAcc.derivedTimes(1));
            secs = secs - app.rcsPointsOut(1);
            plot(haxRcsAcc,secs,xx-mean(xx),'LineWidth',2);
            plot(haxRcsAcc,secs,yy-mean(yy),'LineWidth',2);
            plot(haxRcsAcc,secs,zz-mean(zz),'LineWidth',2);
            title('rcs acc');
            ylimsRcsAcc = get(haxRcsAcc,'YLim');
            
            linkaxes([haxDelAcc, haxRcsAcc],'x');

        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            params.delsysMatFileName = app.delsysMatFileName;
            params.rcsFolder = app.rcsFolder;
            params.delsysAllignPoints = app.delsysPointsOut;
            params.rcsAllignPoints = app.rcsPointsOut;
            params.rcsLength = minutes(minutes(app.outdatcompleteAcc.derivedTimes(end)-app.outdatcompleteAcc.derivedTimes(1))); % already in seconds
            params.delsysLength = minutes(minutes( seconds(app.UIAxes_2.UserData.x(end) - app.UIAxes_2.UserData.x(1)) ));
            
            value = app.delsysChannelDropDown.Value;
            idxchan = find(strcmp(value,app.delsysChannelDropDown.Items)==1);
            delsysChannelFieldNames = app.delsysChannelDropDown.Items;
            chanFnUse = delsysChannelFieldNames{idxchan};
            params.delsysChannelUsesForAllignment = chanFnUse;
            
            
            params.rcsChannelUsesForAllignment =  app.rcsChannelDropDown.Value;
            
            params.rcsComputedSr = app.rcsComputedSr;
            params.rcsUniqueSampleRates = length( unique(app.outdatcomplete.samplerate) );
            params.rcsSampleRatesInFile = unique(app.outdatcomplete.samplerate);
            
            fnmsave = fullfile(app.rcsFolder,'delsysAllignInformation.mat');
            save(fnmsave,'params');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RCSPanel = uipanel(app.LeftPanel);
            app.RCSPanel.Title = 'RC+S';
            app.RedrawButton = uibutton(app.RCSPanel, 'push');
            app.RedrawButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawButtonPushed, true);
            app.RedrawButton.Text = 'Redraw';
            app.FindPeaksButton = uibutton(app.RCSPanel, 'push');
            app.FindPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindPeaksButtonPushed, true);
            app.FindPeaksButton.Text = 'Find Peaks';
            app.channelDropDownLabel = uilabel(app.RCSPanel);
            app.channelDropDownLabel.Text = 'channel';
            app.rcsChannelDropDown = uidropdown(app.RCSPanel);
            app.rcsChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @rcsChannelDropDownValueChanged, true);
            app.DelsysPanel = uipanel(app.LeftPanel);
            app.DelsysPanel.Title = 'Delsys';
            app.RedrawButton_2 = uibutton(app.DelsysPanel, 'push');
            app.RedrawButton_2.Text = 'Redraw';
            app.DelsysFindPeaks = uibutton(app.DelsysPanel, 'push');
            app.DelsysFindPeaks.ButtonPushedFcn = createCallbackFcn(app, @DelsysFindPeaksButtonPushed, true);
            app.DelsysFindPeaks.Text = 'Find Peaks';
            app.channelDropDown_2Label = uilabel(app.DelsysPanel);
            app.channelDropDown_2Label.Text = 'channel';
            app.delsysChannelDropDown = uidropdown(app.DelsysPanel);
            app.delsysChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @delsysChannelDropDownValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes_1 = uiaxes(app.CenterPanel);
            title(app.UIAxes_1, 'RC+S ')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, 'Delsys')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.ComputePanel = uipanel(app.RightPanel);
            app.ComputePanel.Title = 'Compute';
            app.ComputeButton = uibutton(app.ComputePanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.checkButton = uibutton(app.ComputePanel, 'push');
            app.checkButton.ButtonPushedFcn = createCallbackFcn(app, @checkButtonPushed, true);
            app.checkButton.Text = 'check';
            app.DelsysAccDropDownLabel = uilabel(app.ComputePanel);
            app.DelsysAccDropDownLabel.Text = 'Delsys Acc';
            app.DelsysAccDropDown = uidropdown(app.ComputePanel);
            app.RCSSRLabel = uilabel(app.ComputePanel);
            app.RCSSRLabel.Text = 'RC+S SR:';
            app.RCSsrOUT = uilabel(app.ComputePanel);
            app.RCSsrOUT.Text = 'NA';
            app.saveButton = uibutton(app.ComputePanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = allignRCS_Delsys_Data_v2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end