classdef computeSpacing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        resultsTextAreaLabel            matlab.ui.control.Label
        resultsTextArea                 matlab.ui.control.TextArea
        computeButton                   matlab.ui.control.Button
        TipPanel                        matlab.ui.container.Panel
        xEditField_2Label               matlab.ui.control.Label
        xEditField_2                    matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        yEditField_2                    matlab.ui.control.NumericEditField
        zEditField_2Label               matlab.ui.control.Label
        zEditField_2                    matlab.ui.control.NumericEditField
        EntryPanel                      matlab.ui.container.Panel
        xEditField_3Label               matlab.ui.control.Label
        xEditField_3                    matlab.ui.control.NumericEditField
        yEditField_3Label               matlab.ui.control.Label
        yEditField_3                    matlab.ui.control.NumericEditField
        zEditField_3Label               matlab.ui.control.Label
        zEditField_3                    matlab.ui.control.NumericEditField
        electrodespacingEditFieldLabel  matlab.ui.control.Label
        electrodespacingEditField       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            %%
            distances = str2num(app.electrodespacingEditField.Value); 
            for d = 1:length(distances)
                leadTip   = [app.xEditField_2.Value, ...
                    app.yEditField_2.Value, ...
                    app.zEditField_2.Value];
                entry   = [app.xEditField_3.Value, ...
                    app.yEditField_3.Value, ...
                    app.zEditField_3.Value];
                diff  = entry - leadTip;
                nAB = diff ./ sqrt(sum(diff .^ 2, 2));   % Normalize
                distnaceAdd = distances(d);
                contactLocation(d,:) = leadTip + distnaceAdd * nAB;
            end
            
            % plot
            hfig = figure;
            hold on;
            sz = 300;
            hplt = plot3([leadTip(1) entry(1)],...
                [leadTip(2) entry(2)],...
                [leadTip(3) entry(3)]);
            hplt.LineWidth = 5;
            hplt.Color = [ [128 128 128]./255 0.5];
            scatter3(leadTip(1),leadTip(2),leadTip(3),sz,'filled')
            scatter3(entry(1),entry(2),entry(3),sz,'filled')
            for d = 1:size(contactLocation,1)
                scatter3(contactLocation(d,1),contactLocation(d,2),contactLocation(d,3),sz,'filled')
            end
            legend('Lead','Lead Tip','Entry','C1','C2','C3','C4');
            % print results
            
            row = 1;
            textUse{row,1} = sprintf('lead tip, x = %.3f,  y = %.3f,   z = %.3f, ',...
                leadTip(1),leadTip(2),leadTip(3)); 
            row = row+1; 
            
            textUse{row,1} = sprintf('entry, x = %.3f,  y = %.3f,   z = %.3f, ',...
                entry(1),entry(2),entry(3)); 
            row = row+1; 
            
            for d = 1:size(contactLocation,1)
                textUse{row,1} = sprintf('contact %d at %.2f, x = %.3f,  y = %.3f,   z = %.3f, ',...
                  d,distances(d),contactLocation(d,1),contactLocation(d,2),contactLocation(d,3));
            row = row+1;
            end
            app.resultsTextArea.Value = textUse;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.resultsTextAreaLabel = uilabel(app.UIFigure);
            app.resultsTextAreaLabel.Text = 'results';
            app.resultsTextArea = uitextarea(app.UIFigure);
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            app.TipPanel = uipanel(app.UIFigure);
            app.TipPanel.Title = 'Tip';
            app.xEditField_2Label = uilabel(app.TipPanel);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.TipPanel, 'numeric');
            app.yEditField_2Label = uilabel(app.TipPanel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.TipPanel, 'numeric');
            app.zEditField_2Label = uilabel(app.TipPanel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.TipPanel, 'numeric');
            app.EntryPanel = uipanel(app.UIFigure);
            app.EntryPanel.Title = 'Entry';
            app.xEditField_3Label = uilabel(app.EntryPanel);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.EntryPanel, 'numeric');
            app.yEditField_3Label = uilabel(app.EntryPanel);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.EntryPanel, 'numeric');
            app.zEditField_3Label = uilabel(app.EntryPanel);
            app.zEditField_3Label.Text = 'z';
            app.zEditField_3 = uieditfield(app.EntryPanel, 'numeric');
            app.electrodespacingEditFieldLabel = uilabel(app.UIFigure);
            app.electrodespacingEditFieldLabel.Text = 'electrode spacing';
            app.electrodespacingEditField = uieditfield(app.UIFigure, 'text');
            app.electrodespacingEditField.Value = '0.75 3.75 6.75 9.75';
        end
    end

    methods (Access = public)

        % Construct app
        function app = computeSpacing

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end