classdef databaseDataSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        dataselectorbydateTab  matlab.ui.container.Tab
        PatientListBoxLabel    matlab.ui.control.Label
        PatientListBox         matlab.ui.control.ListBox
        SideListBoxLabel       matlab.ui.control.Label
        SideListBox            matlab.ui.control.ListBox
        YearListBoxLabel       matlab.ui.control.Label
        YearListBox            matlab.ui.control.ListBox
        MonthListBoxLabel      matlab.ui.control.Label
        MonthListBox           matlab.ui.control.ListBox
        DayListBoxLabel        matlab.ui.control.Label
        DayListBox             matlab.ui.control.ListBox
        Tab2                   matlab.ui.container.Tab
        UITable                matlab.ui.control.Table
    end

    
    properties (Access = public)
        masterTable % Description
        
    end
    
    methods (Access = public)
        
        function results = updateAllTables(app)
            % get idx for patients
            patientsSelects = app.PatientListBox.Value;
            idxPatient = [];
            for p = 1:length(patientsSelects)
                idxPatient(:,p)  =   strcmp(patientsSelects{p} , app.masterTable.patient);
            end
            idxPatientKeep = sum(idxPatient,2) > 0;
            tblPatient = app.masterTable(idxPatientKeep,:);
            
            % get idx for side
            sideSelects = app.SideListBox.Value;
            idxSides = [];
            for s = 1:length(sideSelects)
                idxSides(:,s)  =   strcmp(sideSelects{s} , tblPatient.side);
            end
            idxKeepSides = sum(idxSides,2) > 0;
            
            tblPatientAndSide = tblPatient(idxKeepSides,:);
            
            % get idx for year
            [yy,mm,dd] = ymd(tblPatientAndSide.timeStart);
            yearSelects = cellfun(@(x) str2num(x), app.YearListBox.Value);
            idxYears = [];
            for y = 1:length(yearSelects)
                idxYears(:,y)  =   yearSelects(y) == yy;
            end
            idxKeepYear = sum(idxYears,2) > 0;
            
            tblPatSidYer = tblPatientAndSide(idxKeepYear,:);
            
            % get idx for month
            if isempty(app.MonthListBox.Value) % if month is empty, have to recompute 
                % month
                [yy,mm,dd] = ymd(tblPatSidYer.timeStart);
                unqmonths = unique(mm);
                unqmonthsCell = {};
                for u = 1:length(unqmonths)
                    unqmonthsCell{u,1} = sprintf('%d',unqmonths(u));
                end
                app.MonthListBox.Items = unqmonthsCell;
                app.MonthListBox.Multiselect = 'on';
                app.MonthListBox.Value = app.MonthListBox.Items;
            end
            
            if ~isempty(tblPatSidYer)
                [yy,mm,dd] = ymd(tblPatSidYer.timeStart);
                monthSelects = cellfun(@(x) str2num(x), app.MonthListBox.Value);
                idxMonths = [];
                for m = 1:length(monthSelects)
                    idxMonths(:,m)  =   monthSelects(m) == mm;
                end
                idxKeepMonths = sum(idxMonths,2) > 0;
                tblPatSidYerMo = tblPatSidYer(idxKeepMonths,:);
            else
                tblPatSidYerMo = table();
                app.MonthListBox.Items = {};
            end
            
            % get idx for day
            if isempty(app.DayListBox.Value) % if day is empty, have to recompute
                % day
                [yy,mm,dd] = ymd(tblPatSidYerMo.timeStart);
                unqdays = unique(dd);
                unqdaysCell = {};
                for u = 1:length(unqdays)
                    unqdaysCell{u,1} = sprintf('%d',unqdays(u));
                end
                app.DayListBox.Items = unqdaysCell;
                app.DayListBox.Multiselect = 'on';
                app.DayListBox.Value = app.DayListBox.Items;
            end

            if ~isempty(tblPatSidYerMo)
                [yy,mm,dd] = ymd(tblPatSidYerMo.timeStart);
                daySelects = cellfun(@(x) str2num(x), app.DayListBox.Value);
                idxDays = [];
                for d = 1:length(daySelects)
                    idxDays(:,d)  =   daySelects(d) == dd;
                end
                idxKeepDays = sum(idxDays,2) > 0;
                tblPatSidYerMoDay = tblPatSidYerMo(idxKeepDays,:);
            else
                tblPatSidYerMoDay = table();
                app.DayListBox.Items = {};
            end
            
            results = tblPatSidYerMoDay;


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, masterTableLight)
            app.masterTable = masterTableLight;
            % populate all table
            idxkeep = cellfun(@(x) any(strfind(x,'RCS')), app.masterTable.patient);
            app.masterTable =  app.masterTable(idxkeep,:);
            
            % patient
            unqpatients = unique(app.masterTable.patient);
            app.PatientListBox.Items = unqpatients;
            app.PatientListBox.Multiselect = 'on';
            app.PatientListBox.Value = app.PatientListBox.Items;
            
            % side
            unqsides = unique(app.masterTable.side);
            app.SideListBox.Items = unqsides;
            app.SideListBox.Multiselect = 'on';
            app.SideListBox.Value = app.SideListBox.Items;
            
            % year
            [y,m,d] = ymd(app.masterTable.timeStart);
            unqyears = unique(y);
            unqYearsCell = {};
            for u = 1:length(unqyears)
                unqYearsCell{u,1} = sprintf('%d',unqyears(u));
            end
            app.YearListBox.Items = unqYearsCell;
            app.YearListBox.Multiselect = 'on';
            app.YearListBox.Value = app.YearListBox.Items;
            
            % month
            unqmonths = unique(m);
            unqmonthsCell = {};
            for u = 1:length(unqmonths)
                unqmonthsCell{u,1} = sprintf('%d',unqmonths(u));
            end
            app.MonthListBox.Items = unqmonthsCell;
            app.MonthListBox.Multiselect = 'on';
            app.MonthListBox.Value = app.MonthListBox.Items;
            
            % day
            unqdays = unique(d);
            unqdaysCell = {};
            for u = 1:length(unqdays)
                unqdaysCell{u,1} = sprintf('%d',unqdays(u));
            end
            app.DayListBox.Items = unqdaysCell;
            app.DayListBox.Multiselect = 'on';
            app.DayListBox.Value = app.DayListBox.Items;
            
            updateAllTables(app)
            
        end

        % Value changed function: PatientListBox
        function PatientListBoxValueChanged(app, event)
            value = app.PatientListBox.Value;
            updateAllTables(app);
        end

        % Value changed function: SideListBox
        function SideListBoxValueChanged(app, event)
            value = app.SideListBox.Value;
            updateAllTables(app);
        end

        % Value changed function: YearListBox
        function YearListBoxValueChanged(app, event)
            value = app.YearListBox.Value;
            updateAllTables(app);
        end

        % Value changed function: MonthListBox
        function MonthListBoxValueChanged(app, event)
            value = app.MonthListBox.Value;
            updateAllTables(app);
        end

        % Value changed function: DayListBox
        function DayListBoxValueChanged(app, event)
            value = app.DayListBox.Value;
            updateAllTables(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.dataselectorbydateTab = uitab(app.TabGroup);
            app.dataselectorbydateTab.Title = 'data selector - by date';
            app.PatientListBoxLabel = uilabel(app.dataselectorbydateTab);
            app.PatientListBoxLabel.Text = 'Patient';
            app.PatientListBox = uilistbox(app.dataselectorbydateTab);
            app.PatientListBox.ValueChangedFcn = createCallbackFcn(app, @PatientListBoxValueChanged, true);
            app.SideListBoxLabel = uilabel(app.dataselectorbydateTab);
            app.SideListBoxLabel.Text = 'Side';
            app.SideListBox = uilistbox(app.dataselectorbydateTab);
            app.SideListBox.ValueChangedFcn = createCallbackFcn(app, @SideListBoxValueChanged, true);
            app.YearListBoxLabel = uilabel(app.dataselectorbydateTab);
            app.YearListBoxLabel.Text = 'Year';
            app.YearListBox = uilistbox(app.dataselectorbydateTab);
            app.YearListBox.ValueChangedFcn = createCallbackFcn(app, @YearListBoxValueChanged, true);
            app.MonthListBoxLabel = uilabel(app.dataselectorbydateTab);
            app.MonthListBoxLabel.Text = 'Month';
            app.MonthListBox = uilistbox(app.dataselectorbydateTab);
            app.MonthListBox.ValueChangedFcn = createCallbackFcn(app, @MonthListBoxValueChanged, true);
            app.DayListBoxLabel = uilabel(app.dataselectorbydateTab);
            app.DayListBoxLabel.Text = 'Day';
            app.DayListBox = uilistbox(app.dataselectorbydateTab);
            app.DayListBox.ValueChangedFcn = createCallbackFcn(app, @DayListBoxValueChanged, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = databaseDataSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end