classdef fit_2d_detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        offpercentileEditFieldLabel  matlab.ui.control.Label
        offpercentileEditField       matlab.ui.control.NumericEditField
        onpercentileEditFieldLabel   matlab.ui.control.Label
        onpercentileEditField        matlab.ui.control.NumericEditField
        offminmAEditFieldLabel       matlab.ui.control.Label
        offminmAEditField            matlab.ui.control.NumericEditField
        onminmAEditFieldLabel        matlab.ui.control.Label
        onminmAEditField             matlab.ui.control.NumericEditField
        offmaxmAEditFieldLabel       matlab.ui.control.Label
        offmaxmAEditField            matlab.ui.control.NumericEditField
        onmaxmAEditFieldLabel        matlab.ui.control.Label
        onmaxmAEditField             matlab.ui.control.NumericEditField
        FITButton                    matlab.ui.control.Button
        channelDropDownLabel         matlab.ui.control.Label
        channelDropDown              matlab.ui.control.DropDown
        peakfreqxEditFieldLabel      matlab.ui.control.Label
        peakfreqxEditField           matlab.ui.control.NumericEditField
        bwxEditFieldLabel            matlab.ui.control.Label
        bwxEditField                 matlab.ui.control.NumericEditField
        H_Plot                       matlab.ui.control.UIAxes
        w1Label                      matlab.ui.control.Label
        w2Label                      matlab.ui.control.Label
        bLabel                       matlab.ui.control.Label
        w1_out                       matlab.ui.control.Label
        w2_out                       matlab.ui.control.Label
        b_out                        matlab.ui.control.Label
        updateplotButton             matlab.ui.control.Button
        peakfreqyEditFieldLabel      matlab.ui.control.Label
        peakfreqyEditField           matlab.ui.control.NumericEditField
        bwyEditFieldLabel            matlab.ui.control.Label
        bwyEditField                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        dirname % Description
        data % data structure
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function results = plot_data(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if length(varargin)==1
                app.dirname = varargin{1};
            end
            updateplotButtonPushed(app)
            
        end

        % Button pushed function: updateplotButton
        function updateplotButtonPushed(app, event)
            cla(app.H_Plot);
            close all;
            [pn,fn] = fileparts(app.dirname);
            %% load data
            patient = fn(1:end-1);
            side = fn(end);
            clear outdat;
            folderuse = app.dirname;
            meds = {'off_meds','on_meds'};
            for m = 1:length(meds)
                rootfold = fullfile(folderuse,meds{m});
                ff = findFilesBVQX(rootfold,'rest*.mat');
                for f = 1:length(ff)
                    load(ff{f});
                    [pn,fn] = fileparts(ff{f});
                    
                    outdat.(meds{m})(f).powerChunk = powerChunk;
                    outdat.(meds{m})(f).outdatachunk = outdatachunk;
                    outdat.(meds{m})(f).powerMeta = powerMeta;
                    outdat.(meds{m})(f).eventTable = eventTable;
                    outdat.(meds{m})(f).current = str2num(fn(end-2:end));
                    if isempty(outdatachunk)
                        fprintf('empty file:\t %s\n',ff{f});
                    end
                end
            end
            %% plot time domain data
            hfig = figure;
            hfig.Color = 'w';
            for cn = 1:4
                hsb = subplot(2,2,cn);
                hold on;
                plotFreqPatches(hsb)
            end
            
            meds = {'off_meds','on_meds'};
            for m = 1:length(meds)
                dat = outdat.(meds{m});
                if m == 1
                    colorUse = [0.8 0 0.5];
                else
                    colorUse = [0 0.8 0.5];
                end
                chanorder = [3 4 1 2];
                for cn = 1:4
                    fnuse = sprintf('key%d',chanorder(cn)-1);
                    ttluse = outRec(end).tdData(chanorder(cn)).chanFullStr;
                    if chanorder(cn) > 2
                        area = 'M1';
                    else
                        area = 'STN';
                    end
                    ttluse = sprintf('%s +%s-%s',area,...
                        outRec(end).tdData(chanorder(cn)).plusInput,...
                        outRec(end).tdData(chanorder(cn)).minusInput);
                    subplot(2,2,cn);
                    hold on;
                    for c = 1:length(dat)
                        % get times
                        tdTAble = dat(c).outdatachunk;
                        tdtimes = tdTAble.derivedTimes - tdTAble.derivedTimes(1);
                        timeabove = tdtimes(end) - seconds(30);
                        idxkeep   = tdtimes >= timeabove;
                        
                        tddat = tdTAble.(fnuse)(idxkeep,:);
                        sr  = unique(tdTAble.samplerate(idxkeep,:));
                        [fftOut,ff]   = pwelch(tddat,sr,sr/2,0:1:sr/2,sr,'psd');
                        outdat.(meds{m})(c).ff(chanorder(cn),:) = ff;
                        outdat.(meds{m})(c).fftOut(chanorder(cn),:) = log10(fftOut);
                        ampwrite = sprintf('%0.1fmA',dat(c).current);
                        hp = plot(ff,log10(fftOut));
                        hp.LineWidth = 2;
                        hp.Color = colorUse;
                        hpout(m,c,cn) = hp;
                        if dat(c).current > 1
                            hp.LineStyle = '-.';
                        else
                            hp.LineStyle = '-';
                        end
                        
                    end
                    xlim([1 100]);
                    xlabel('Freq (Hz)');
                    ylabel('Power (log_1_0\muV^2/Hz)');
                    title(ttluse);
                    set(gca,'FontSize',16);
                    
                end
            end
            largeTitleUse = sprintf('%s %s',patient,side);
            sgtitle(largeTitleUse,'FontSize',24);
            %% compute the channel data based on input from app
            peaks_x  = app.peakfreqxEditField.Value;
            bw_x  = app.bwxEditField.Value; 
            peaks_y  = app.peakfreqyEditField.Value;
            bw_y  = app.bwyEditField.Value;
            peaks_x = app.peakfreqxEditField.Value;
            peaks_y = app.peakfreqyEditField.Value;
            prctil_use(1) = app.offpercentileEditField.Value;
            prctil_use(2) = app.onpercentileEditField.Value;

            
            for m = 1:length(meds)
                dat = outdat.(meds{m});
                if m == 1
                    colorUse = [0.8 0 0.5];
                else
                    colorUse = [0 0.8 0.5];
                end
                chanorder(1) = str2num(app.channelDropDown.Value);
                for cn = 1 % loop on sense electrodes
                    ttluse = outRec(end).tdData(cn).chanFullStr;
                    if chanorder(cn) > 2
                        area = 'M1';
                    else
                        area = 'STN';
                    end
                    
                    hold on;
                    currents = [];
                    meanpower = [];
                    
                    for c = 1:length(dat) % loop on stim current
                        
                        ff = outdat.(meds{m})(c).ff(chanorder(cn),:);
                        fftOut  = outdat.(meds{m})(c).fftOut(chanorder(cn),:);
                        current = outdat.(meds{m})(c).current;
                        % peaks x
                        idxuse = (ff >= (peaks_x-bw_x) ) &  (ff <= (peaks_x+bw_x) );
                        xx(c,m) = prctile(fftOut(idxuse),prctil_use(m));
                        % peaks y
                        idxuse = (ff >= (peaks_y-bw_y) ) &  (ff <= (peaks_y+bw_y) );
                        yy(c,m) = prctile(fftOut(idxuse),prctil_use(m));
                        currents_label(c) = current;
                        % plot power
                        hsc = scatter(app.H_Plot,xx(c,m),yy(c,m),2e2,'filled');
                        hold(app.H_Plot,'on');
                        hsc.MarkerFaceColor = colorUse;
                        hsc.MarkerFaceAlpha = 0.6;
                        
                        % plot current in each scatter plot
                        ampwrite = sprintf('%0.1f',dat(c).current);
                        %                     text(current, meanpower(c), ampwrite);
                        
                        
                    end
                    hfig1 = figure;
                    hfig1.Color = 'w';
                    scatter(currents_label,xx(:,m))
                    xlabel('current (mA)')
                    ylabel('artifact of current (power 120Hz)')
                    title(ttluse);
                    set(gca,'FontSize',16)
                    freqsuse = ff(idxuse);
                    bandsused(1) = min(freqsuse);
                    bandsused(2) = max(freqsuse);                ttluse = sprintf('%s +%s-%s (%d-%d Hz)',area,...
                        outRec(end).tdData(chanorder(cn)).plusInput,...
                        outRec(end).tdData(chanorder(cn)).minusInput,...
                        bandsused(1),   bandsused(2));
                    
                    title(app.H_Plot,ttluse);
                    
                    %                 ylbtitle = sprintf('Power computed from TD (%d-%d Hz)',bandsused(1), bandsused(2));
                    %                ylabel(ylbtitle);
                    
                    ylabel(app.H_Plot,'Power (log_1_0\muV^2/Hz)');
                    xlabel(app.H_Plot,'Current (mA)');
                end
                
            end
%             largeTitleUse = sprintf('%s %s',patient,side);
%             sgtitle(largeTitleUse,'FontSize',24);
            
            app.data = outdat;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.offpercentileEditFieldLabel = uilabel(app.UIFigure);
            app.offpercentileEditFieldLabel.Text = 'off percentile';
            app.offpercentileEditField = uieditfield(app.UIFigure, 'numeric');
            app.offpercentileEditField.Value = 0.5;
            app.onpercentileEditFieldLabel = uilabel(app.UIFigure);
            app.onpercentileEditFieldLabel.Text = 'on percentile';
            app.onpercentileEditField = uieditfield(app.UIFigure, 'numeric');
            app.onpercentileEditField.Value = 0.5;
            app.offminmAEditFieldLabel = uilabel(app.UIFigure);
            app.offminmAEditFieldLabel.Text = 'off min mA';
            app.offminmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.offminmAEditField.Value = 1;
            app.onminmAEditFieldLabel = uilabel(app.UIFigure);
            app.onminmAEditFieldLabel.Text = 'on min mA';
            app.onminmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.onminmAEditField.Value = 1;
            app.offmaxmAEditFieldLabel = uilabel(app.UIFigure);
            app.offmaxmAEditFieldLabel.Text = 'off max mA';
            app.offmaxmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.offmaxmAEditField.Value = 2;
            app.onmaxmAEditFieldLabel = uilabel(app.UIFigure);
            app.onmaxmAEditFieldLabel.Text = 'on max mA';
            app.onmaxmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.onmaxmAEditField.Value = 2;
            app.FITButton = uibutton(app.UIFigure, 'push');
            app.FITButton.Text = 'FIT';
            app.channelDropDownLabel = uilabel(app.UIFigure);
            app.channelDropDownLabel.Text = 'channel';
            app.channelDropDown = uidropdown(app.UIFigure);
            app.channelDropDown.Items = {'1', '2', '3', '4'};
            app.channelDropDown.Value = '1';
            app.peakfreqxEditFieldLabel = uilabel(app.UIFigure);
            app.peakfreqxEditFieldLabel.Text = 'peak freq x';
            app.peakfreqxEditField = uieditfield(app.UIFigure, 'numeric');
            app.peakfreqxEditField.Value = 15;
            app.bwxEditFieldLabel = uilabel(app.UIFigure);
            app.bwxEditFieldLabel.Text = 'bw x';
            app.bwxEditField = uieditfield(app.UIFigure, 'numeric');
            app.bwxEditField.Value = 2.5;
            app.H_Plot = uiaxes(app.UIFigure);
            title(app.H_Plot, 'Title')
            xlabel(app.H_Plot, 'X')
            ylabel(app.H_Plot, 'Y')
            app.w1Label = uilabel(app.UIFigure);
            app.w1Label.Text = 'w1';
            app.w2Label = uilabel(app.UIFigure);
            app.w2Label.Text = 'w2';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b';
            app.w1_out = uilabel(app.UIFigure);
            app.w1_out.Text = 'NA';
            app.w2_out = uilabel(app.UIFigure);
            app.w2_out.Text = 'NA';
            app.b_out = uilabel(app.UIFigure);
            app.b_out.Text = 'NA';
            app.updateplotButton = uibutton(app.UIFigure, 'push');
            app.updateplotButton.ButtonPushedFcn = createCallbackFcn(app, @updateplotButtonPushed, true);
            app.updateplotButton.Text = 'update plot';
            app.peakfreqyEditFieldLabel = uilabel(app.UIFigure);
            app.peakfreqyEditFieldLabel.Text = 'peak freq y';
            app.peakfreqyEditField = uieditfield(app.UIFigure, 'numeric');
            app.peakfreqyEditField.Value = 118;
            app.bwyEditFieldLabel = uilabel(app.UIFigure);
            app.bwyEditFieldLabel.Text = 'bw y';
            app.bwyEditField = uieditfield(app.UIFigure, 'numeric');
            app.bwyEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fit_2d_detector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end