classdef loggerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        logTextAreaLabel         matlab.ui.control.Label
        logTextArea              matlab.ui.control.TextArea
        startfileButton          matlab.ui.control.Button
        texttologEditFieldLabel  matlab.ui.control.Label
        texttologEditField       matlab.ui.control.EditField
        logButton                matlab.ui.control.Button
        fileName                 matlab.ui.control.Label
        currentfileLabel         matlab.ui.control.Label
        closefileButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        log % Description
        fid % file id 
    end
    
    methods (Access = public)
        
        function logger(app,txtToLog)
            totalTxtLog = sprintf('[%s]:\t%s',...
                datestr(datetime('now')),...
                txtToLog);
            idxuse = size(app.log,2) + 1;
            app.log{idxuse} =  totalTxtLog;
            app.logTextArea.Value = fliplr(app.log);
            if isempty(app.fid)
                for i = 1:5
                    app.fileName.FontColor = [0.9 0 0 ];
                    app.fileName.FontWeight = 'Bold';
                    pause(0.1)
                    app.fileName.FontColor = [0 0 0 ];
                    app.fileName.FontWeight = 'normal';
                    pause(0.1);
                    
                end
            else
                fprintf(app.fid,[totalTxtLog ' \n']);
            end
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: startfileButton
        function startfileButtonPushed(app, event)
            fnm = sprintf('log_%s.txt',datestr(datetime('now'),30));
            app.fileName.Text = fnm;
            dirname = mkdir(fullfile(pwd,'logs'));
            app.fid = fopen(fullfile(pwd,'logs',fnm),'wt+');
            app.log = {}; 
            app.logTextArea.Value = {''};
        end

        % Button pushed function: logButton
        function logButtonPushed(app, event)
            txtToLog = app.texttologEditField.Value;
            app.logger(txtToLog); 
            app.texttologEditField.Value = '';
        end

        % Button pushed function: closefileButton
        function closefileButtonPushed(app, event)
            if ~isempty(app.fid)
                fclose(app.fid);
            end
            app.fid = [];
            app.fileName.Text = 'file note started';
            app.logTextArea.Value = {''};
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            x = 2;
        end

        % Value changed function: texttologEditField
        function texttologEditFieldValueChanged(app, event)
            value = app.texttologEditField.Value;
            X = 2;
        end

        % Value changing function: texttologEditField
        function texttologEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if strcmp(changingValue,'return')
                x = 2;
            end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.logTextAreaLabel = uilabel(app.UIFigure);
            app.logTextAreaLabel.Text = 'log';
            app.logTextArea = uitextarea(app.UIFigure);
            app.startfileButton = uibutton(app.UIFigure, 'push');
            app.startfileButton.ButtonPushedFcn = createCallbackFcn(app, @startfileButtonPushed, true);
            app.startfileButton.Text = 'start file';
            app.texttologEditFieldLabel = uilabel(app.UIFigure);
            app.texttologEditFieldLabel.Text = 'text to log';
            app.texttologEditField = uieditfield(app.UIFigure, 'text');
            app.texttologEditField.ValueChangedFcn = createCallbackFcn(app, @texttologEditFieldValueChanged, true);
            app.texttologEditField.ValueChangingFcn = createCallbackFcn(app, @texttologEditFieldValueChanging, true);
            app.logButton = uibutton(app.UIFigure, 'push');
            app.logButton.ButtonPushedFcn = createCallbackFcn(app, @logButtonPushed, true);
            app.logButton.Text = 'log';
            app.fileName = uilabel(app.UIFigure);
            app.fileName.Text = 'file not started!';
            app.currentfileLabel = uilabel(app.UIFigure);
            app.currentfileLabel.Text = 'current file:';
            app.closefileButton = uibutton(app.UIFigure, 'push');
            app.closefileButton.ButtonPushedFcn = createCallbackFcn(app, @closefileButtonPushed, true);
            app.closefileButton.Text = 'close file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = loggerApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end