classdef metaDataUtilityDelsysVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DataChunkMetaDataSaverLabel  matlab.ui.control.Label
        MetaDataPanel                matlab.ui.container.Panel
        TaskDropDownLabel            matlab.ui.control.Label
        TaskDropDown                 matlab.ui.control.DropDown
        MedDropDownLabel             matlab.ui.control.Label
        MedDropDown                  matlab.ui.control.DropDown
        medtimeEditFieldLabel        matlab.ui.control.Label
        medtimeEditField             matlab.ui.control.EditField
        tasknotesEditFieldLabel      matlab.ui.control.Label
        tasknotesEditField           matlab.ui.control.EditField
        InstructionsPanel            matlab.ui.container.Panel
        InstructionsTextArea         matlab.ui.control.TextArea
        DelsysDataPanel              matlab.ui.container.Panel
        DataAllignementStatusLabel   matlab.ui.control.Label
        NALabel                      matlab.ui.control.Label
        DisplayAllignedDataButton    matlab.ui.control.Button
        AllignDataButton             matlab.ui.control.Button
        DelsysFileLabel              matlab.ui.control.Label
        NALabel_2                    matlab.ui.control.Label
        ChooseDelsysFileButton       matlab.ui.control.Button
        savedelsysinfoButton         matlab.ui.control.Button
        VideoMetaDataPanel           matlab.ui.container.Panel
        choosevideofileButton        matlab.ui.control.Button
        savevideoinfoButton          matlab.ui.control.Button
        VideoFileLabel               matlab.ui.control.Label
        NALabel_3                    matlab.ui.control.Label
        videotimeEditFieldLabel      matlab.ui.control.Label
        videotimeEditField           matlab.ui.control.EditField
        delsystimeEditFieldLabel     matlab.ui.control.Label
        delsystimeEditField          matlab.ui.control.EditField
        VideoAllignmentstatusLabel   matlab.ui.control.Label
        NALabel_4                    matlab.ui.control.Label
        DirNameLabel                 matlab.ui.control.Label
        DirNameLabelReport           matlab.ui.control.Label
        choosedirButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        dirname % directory name 
        params % params for data chunk 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin == 2 
                app.dirname = varargin{1};
            elseif nargin == 3
                app.dirname = varargin{1};
                app.params  = varargin{2};
            else
                app.dirname = []; 
                app.params  = []; 
            end
            % loop for externalSensors.mat file in the directory 
            
        end

        % Button pushed function: ChooseDelsysFileButton
        function ChooseDelsysFileButtonPushed(app, event)
            [pn,fn,ext] = uigetfile('*.mat'); 
            app.delsysfn = fullfile(pn,fn); 
        end

        % Button pushed function: choosedirButton
        function choosedirButtonPushed(app, event)
            [app.dirname] = uigetdir();
            app.DirNameLabelReport.Text = app.dirname; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DataChunkMetaDataSaverLabel = uilabel(app.UIFigure);
            app.DataChunkMetaDataSaverLabel.Text = 'Data Chunk Meta Data Saver';
            app.MetaDataPanel = uipanel(app.UIFigure);
            app.MetaDataPanel.Title = 'Meta Data';
            app.TaskDropDownLabel = uilabel(app.MetaDataPanel);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.MetaDataPanel);
            app.TaskDropDown.Items = {'rest', 'iPad', 'walking', 'standing', 'dynamic tremor', 'finger tapping', 'driving', 'sleeping', 'other'};
            app.TaskDropDown.Value = 'rest';
            app.MedDropDownLabel = uilabel(app.MetaDataPanel);
            app.MedDropDownLabel.Text = 'Med';
            app.MedDropDown = uidropdown(app.MetaDataPanel);
            app.MedDropDown.Items = {'on', 'off'};
            app.MedDropDown.Value = 'on';
            app.medtimeEditFieldLabel = uilabel(app.MetaDataPanel);
            app.medtimeEditFieldLabel.Text = 'med time ';
            app.medtimeEditField = uieditfield(app.MetaDataPanel, 'text');
            app.tasknotesEditFieldLabel = uilabel(app.MetaDataPanel);
            app.tasknotesEditFieldLabel.Text = 'task notes';
            app.tasknotesEditField = uieditfield(app.MetaDataPanel, 'text');
            app.InstructionsPanel = uipanel(app.UIFigure);
            app.InstructionsPanel.Title = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.InstructionsPanel);
            app.InstructionsTextArea.Value = {'This utility GUI saves meta data for RC+S. '; 'Each task is "chunked" and saved wtih video chunk (if exists), '; 'Delsys Chunk and RC+S data chunk. '};
            app.DelsysDataPanel = uipanel(app.UIFigure);
            app.DelsysDataPanel.Title = 'Delsys Data';
            app.DataAllignementStatusLabel = uilabel(app.DelsysDataPanel);
            app.DataAllignementStatusLabel.Text = 'Data Allignement Status';
            app.NALabel = uilabel(app.DelsysDataPanel);
            app.NALabel.Text = 'NA';
            app.DisplayAllignedDataButton = uibutton(app.DelsysDataPanel, 'push');
            app.DisplayAllignedDataButton.Text = 'Display Alligned Data';
            app.AllignDataButton = uibutton(app.DelsysDataPanel, 'push');
            app.AllignDataButton.Text = 'Allign Data';
            app.DelsysFileLabel = uilabel(app.DelsysDataPanel);
            app.DelsysFileLabel.Text = 'Delsys File:';
            app.NALabel_2 = uilabel(app.DelsysDataPanel);
            app.NALabel_2.Text = 'NA';
            app.ChooseDelsysFileButton = uibutton(app.DelsysDataPanel, 'push');
            app.ChooseDelsysFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDelsysFileButtonPushed, true);
            app.ChooseDelsysFileButton.Text = 'Choose Delsys File';
            app.savedelsysinfoButton = uibutton(app.DelsysDataPanel, 'push');
            app.savedelsysinfoButton.Text = 'save delsys info';
            app.VideoMetaDataPanel = uipanel(app.UIFigure);
            app.VideoMetaDataPanel.Title = 'Video Meta Data';
            app.choosevideofileButton = uibutton(app.VideoMetaDataPanel, 'push');
            app.choosevideofileButton.Text = 'choose video file';
            app.savevideoinfoButton = uibutton(app.VideoMetaDataPanel, 'push');
            app.savevideoinfoButton.Text = 'save video info';
            app.VideoFileLabel = uilabel(app.VideoMetaDataPanel);
            app.VideoFileLabel.Text = 'Video File ';
            app.NALabel_3 = uilabel(app.VideoMetaDataPanel);
            app.NALabel_3.Text = 'NA';
            app.videotimeEditFieldLabel = uilabel(app.VideoMetaDataPanel);
            app.videotimeEditFieldLabel.Text = 'video time';
            app.videotimeEditField = uieditfield(app.VideoMetaDataPanel, 'text');
            app.delsystimeEditFieldLabel = uilabel(app.VideoMetaDataPanel);
            app.delsystimeEditFieldLabel.Text = 'delsys time';
            app.delsystimeEditField = uieditfield(app.VideoMetaDataPanel, 'text');
            app.VideoAllignmentstatusLabel = uilabel(app.VideoMetaDataPanel);
            app.VideoAllignmentstatusLabel.Text = 'Video Allignment status ';
            app.NALabel_4 = uilabel(app.VideoMetaDataPanel);
            app.NALabel_4.Text = 'NA';
            app.DirNameLabel = uilabel(app.UIFigure);
            app.DirNameLabel.Text = 'Dir Name: ';
            app.DirNameLabelReport = uilabel(app.UIFigure);
            app.DirNameLabelReport.Text = 'NA';
            app.choosedirButton = uibutton(app.UIFigure, 'push');
            app.choosedirButton.ButtonPushedFcn = createCallbackFcn(app, @choosedirButtonPushed, true);
            app.choosedirButton.Text = 'choose dir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metaDataUtilityDelsysVideo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end