classdef plot_combined_data_table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        SelectionTab           matlab.ui.container.Tab
        PlotControlsPanel      matlab.ui.container.Panel
        PlotButton             matlab.ui.control.Button
        popoutCheckBox         matlab.ui.control.CheckBox
        ChoosewhattoplotLabel  matlab.ui.control.Label
        TimeDomainPanel        matlab.ui.container.Panel
        TimeDomainListBox      matlab.ui.control.ListBox
        PowerDomainPanel       matlab.ui.container.Panel
        PowerDomainListBox     matlab.ui.control.ListBox
        AdaptivePanel          matlab.ui.container.Panel
        AdaptiveDomainListBox  matlab.ui.control.ListBox
        ActigraphyPanel        matlab.ui.container.Panel
        ActigraphyListBox      matlab.ui.control.ListBox
        PlotTab                matlab.ui.container.Tab
        MetaDataTab            matlab.ui.container.Tab
        MetaDataListBoxLabel   matlab.ui.control.Label
        MetaDataListBox        matlab.ui.control.ListBox
        UITable                matlab.ui.control.Table
    end

    
    properties (Access = public)
        dataTable % combined daata table 
        dirName  % directory with data 
        fieldnames 
        settingsStruc
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dirName)
            app.dirName = dirName;
            [combinedDataTable, debugTable, timeDomainSettings,powerSettings,...
                fftSettings,metaData,stimSettingsOut,stimMetaData,stimLogSettings,...
                DetectorSettings,AdaptiveStimSettings,AdaptiveRuns_StimSettings] = DEMO_ProcessRCS(app.dirName,2);
            app.settingsStruc.fftSettings = fftSettings;
            app.settingsStruc.stimSettingsOut = stimSettingsOut;
            app.settingsStruc.stimLogSettings = stimLogSettings;
            app.settingsStruc.DetectorSettings = DetectorSettings;
            app.settingsStruc.AdaptiveStimSettings = AdaptiveStimSettings;
            app.settingsStruc.AdaptiveRuns_StimSettings = AdaptiveRuns_StimSettings;
            app.settingsStruc.powerSettings = powerSettings;
            app.settingsStruc.timeDomainSettings = timeDomainSettings;
            app.settingsStruc.stimMetaData = stimMetaData;
            app.settingsStruc.metaData = metaData;
            app.settingsStruc.debugTable = debugTable;
            
            
            app.dataTable = combinedDataTable;
            % populate the headers 
            % time domain 
            app.fieldnames = app.dataTable.Properties.VariableNames';
            
            % time domain:
            idxtd = cellfun(@(x) any(strfind(x,'TD_k')) ,app.fieldnames);
            app.TimeDomainListBox.Items = app.fieldnames(idxtd);
            
            % power domain 
            idxtd = cellfun(@(x) any(strfind(x,'Power_Band')) ,app.fieldnames);
            app.PowerDomainListBox.Items = app.fieldnames(idxtd);
            
            % Adaptive 
            idxtd = cellfun(@(x) any(strfind(x,'Adaptive_')) ,app.fieldnames);
            app.AdaptiveDomainListBox.Items = app.fieldnames(idxtd);
            
            % Actigraphy
            idxtd = cellfun(@(x) any(strfind(x,'Accel_')) ,app.fieldnames);
            app.ActigraphyListBox.Items = app.fieldnames(idxtd);
            app.ActigraphyListBox.Items = app.ActigraphyListBox.Items(1:3); % ugly hack 
            
            % set up meta data table 
            app.MetaDataListBox.Items = fieldnames(app.settingsStruc);
            app.MetaDataListBox.Value = 'timeDomainSettings';
            app.UITable.Data = app.settingsStruc.timeDomainSettings;
            app.UITable.ColumnName = app.settingsStruc.timeDomainSettings.Properties.VariableNames;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % get all teh children and delete them 
            delete(app.PlotTab.Children)
            % plot some data: 
            app.PlotTab.BackgroundColor = [ 1 1 1];
            fieldnamesplot = [app.TimeDomainListBox.Value, ...
              app.PowerDomainListBox.Value,...
              app.AdaptiveDomainListBox.Value,...
              app.ActigraphyListBox.Value];
            nplots = length(fieldnamesplot);
            if app.popoutCheckBox.Value
                hfig = figure;
                hfig.Color = 'w';
                parentUse = hfig; 
            else
                parentUse = app.PlotTab;
            end
            hsb = gobjects();
            for p = 1:nplots
                % for time domain data, allow line breaks, all other types
                % of data, just "join lines" 
                if any(strfind(fieldnamesplot{p},'TD'))
                    y = app.dataTable.(fieldnamesplot{p});
                    x = app.dataTable.localTime;
                else
                    y = app.dataTable.(fieldnamesplot{p});
                    x = app.dataTable.localTime;
                    idxplot = ~isnan(y);
                    y = y(idxplot);
                    x = x(idxplot);
                end
                hsb(p,1) = subplot(nplots,1,p,'Parent',parentUse);
                
                plot(x,y,'Parent',hsb(p,1));
                ttlplot = strrep(fieldnamesplot{p},'_',' ');
                title(ttlplot,'Parent',hsb(p,1))
                set(hsb(p,1),'FontSize',12);
                linkaxes(hsb,'x');
            end
        end

        % Value changed function: MetaDataListBox
        function MetaDataListBoxValueChanged(app, event)
            value = app.MetaDataListBox.Value;
            app.UITable.Data = app.settingsStruc.(value);
            app.UITable.ColumnName = app.settingsStruc.(value).Properties.VariableNames;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectionTab = uitab(app.TabGroup);
            app.SelectionTab.Title = 'Selection';
            app.PlotControlsPanel = uipanel(app.SelectionTab);
            app.PlotControlsPanel.Title = 'Plot Controls';
            app.PlotButton = uibutton(app.PlotControlsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.popoutCheckBox = uicheckbox(app.PlotControlsPanel);
            app.popoutCheckBox.Tooltip = {'If this is selected it will create a new figure with the data'; ' else'; ' it will plot the data in the "plot" tab'};
            app.popoutCheckBox.Text = 'pop out';
            app.popoutCheckBox.Value = true;
            app.ChoosewhattoplotLabel = uilabel(app.SelectionTab);
            app.ChoosewhattoplotLabel.Text = 'Choose what to plot: ';
            app.TimeDomainPanel = uipanel(app.SelectionTab);
            app.TimeDomainPanel.Title = 'Time Domain';
            app.TimeDomainListBox = uilistbox(app.TimeDomainPanel);
            app.TimeDomainListBox.Multiselect = 'on';
            app.TimeDomainListBox.Tooltip = {'Select one or more channels you wish to plot then hit "plot" '};
            app.TimeDomainListBox.Value = {'Item 1'};
            app.PowerDomainPanel = uipanel(app.SelectionTab);
            app.PowerDomainPanel.Title = 'Power Domain';
            app.PowerDomainListBox = uilistbox(app.PowerDomainPanel);
            app.PowerDomainListBox.Multiselect = 'on';
            app.PowerDomainListBox.Tooltip = {'Select one or more channels you wish to plot then hit "plot" '};
            app.PowerDomainListBox.Value = {'Item 1'};
            app.AdaptivePanel = uipanel(app.SelectionTab);
            app.AdaptivePanel.Title = 'Adaptive';
            app.AdaptiveDomainListBox = uilistbox(app.AdaptivePanel);
            app.AdaptiveDomainListBox.Multiselect = 'on';
            app.AdaptiveDomainListBox.Tooltip = {'Select one or more channels you wish to plot then hit "plot" '};
            app.AdaptiveDomainListBox.Value = {'Item 1'};
            app.ActigraphyPanel = uipanel(app.SelectionTab);
            app.ActigraphyPanel.Title = 'Actigraphy';
            app.ActigraphyListBox = uilistbox(app.ActigraphyPanel);
            app.ActigraphyListBox.Multiselect = 'on';
            app.ActigraphyListBox.Tooltip = {'Select one or more channels you wish to plot then hit "plot" '};
            app.ActigraphyListBox.Value = {'Item 1'};
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.MetaDataTab = uitab(app.TabGroup);
            app.MetaDataTab.Title = 'Meta Data';
            app.MetaDataListBoxLabel = uilabel(app.MetaDataTab);
            app.MetaDataListBoxLabel.Text = 'Meta Data';
            app.MetaDataListBox = uilistbox(app.MetaDataTab);
            app.MetaDataListBox.ValueChangedFcn = createCallbackFcn(app, @MetaDataListBoxValueChanged, true);
            app.UITable = uitable(app.MetaDataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_combined_data_table(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end