classdef plot_stim_sweep_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        selectdataTab             matlab.ui.container.Tab
        loaddataButton            matlab.ui.control.Button
        saveidxsButton            matlab.ui.control.Button
        dataplotsPanel            matlab.ui.container.Panel
        loadidxsButton            matlab.ui.control.Button
        montagefileDropDownLabel  matlab.ui.control.Label
        montagefileDropDown       matlab.ui.control.DropDown
        zoomButton                matlab.ui.control.Button
        zoomcurrentButton         matlab.ui.control.Button
        plotpsdsButton            matlab.ui.control.Button
        PSDSTNTab                 matlab.ui.container.Tab
        PlotdataPanelSTN          matlab.ui.container.Panel
        electrodeLabel            matlab.ui.control.Label
        psdElectrodeSelector      matlab.ui.control.ListBox
        stimampLabel              matlab.ui.control.Label
        psdAmpSelector            matlab.ui.control.ListBox
        stimrateLabel             matlab.ui.control.Label
        psdRateSelector           matlab.ui.control.ListBox
        PSDM1Tab                  matlab.ui.container.Tab
        PlotdataPanelM1           matlab.ui.container.Panel
        montageListBoxLabel       matlab.ui.control.Label
        m1MontageSelector         matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        hCurLine % handle to current line
        hCurLineStatus % handle to current line stuats
        
        
        dataDir % Description
        ets % events table start
        ete % events table end
        hpltStart % handles for start plot
        hpltEnd % handles for end plot
        hsub % handles for sub plots
        subTime % to change datetime to duration
        outdatcomplete % table of time domain data
        rawDatSTN % raw data for stn
        rawDatM1 % raw data for m1
        hStn % handles for stn psd
        hM1 % handles for m1 psds
        hpltSTNlines % handles to stn psd lines
        hpltM1lines % handles to m1 psd lines
    end
    
    methods (Access = public)
        
        function LineDown(app,event,src)
            fprintf('line down cur line status %d\n',app.hCurLineStatus);
            app.hCurLine = event;
            app.hCurLineStatus = 1;
        end
        
        function MouseMove(app,event,src)
            if app.hCurLineStatus
                fprintf('mouse move cur line status %d\n',app.hCurLineStatus);
                hUiaxes = get(app.hCurLine,'Parent');
                cp = get ( hUiaxes, 'CurrentPoint' );
                set ( app.hCurLine, 'XData', [cp(1,1) cp(1,1)] );
            end
        end
        
        function MouseUp(app,event,src)
            fprintf('mouse up cur line status %d\n',app.hCurLineStatus);
            app.hCurLineStatus = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            % register callback move lines
            set(app.UIFigure,'WindowButtonMotion',@app.MouseMove);
            set(app.UIFigure,'WindowButtonUpFcn',@app.MouseUp);
            
            app.dataDir= uigetdir();
            [outdatcomplete,outRec,eventTable,outdatcompleteAcc,powerTable] =  MAIN_load_rcs_data_from_folder(app.dataDir);
            outdatcomplete.derivedTimes(1)
            [fnn,pnn] = fileparts(app.dataDir);
            [fnn,sessionName] = fileparts(fnn);
            figTitle = sprintf('%s %s',outdatcomplete.derivedTimes(1),sessionName);
            app.UIFigure.Name = figTitle; 
            
            deviceSettingsFn = fullfile(app.dataDir,'DeviceSettings.json');
            outRec = loadDeviceSettingsForMontage(deviceSettingsFn);
            % figure out add / subtract factor for event times (if pc clock is not same
            % as INS time).
            idxTimeCompare = find(outdatcomplete.PacketRxUnixTime~=0,1);
            packRxTimeRaw  = outdatcomplete.PacketRxUnixTime(idxTimeCompare);
            packtRxTime    =  datetime(packRxTimeRaw/1000,...
                'ConvertFrom','posixTime','TimeZone','America/Los_Angeles','Format','dd-MMM-yyyy HH:mm:ss.SSS');
            derivedTime    = outdatcomplete.derivedTimes(idxTimeCompare);
            timeDiff       = derivedTime - packtRxTime;
            
            
            secs = outdatcomplete.derivedTimes;
            app.subTime = secs(1);
            % find start events
            idxStart = cellfun(@(x) any(strfind(x,'Start')),eventTable.EventType);
            idxEnd = cellfun(@(x) any(strfind(x,'Stop')),eventTable.EventType);
            % populdate controller
            app.montagefileDropDown.Items = eventTable.EventType(idxStart);
            app.montagefileDropDown.ItemsData= 1:sum(idxStart);
            app.montagefileDropDown.Value = 1;
            % plot data
            for c = 1:4
                app.hsub(c) = subplot(4,1,c,'Parent',app.dataplotsPanel);
                hold(app.hsub(c),'on');
                cfnm = sprintf('key%d',c-1);
                y = outdatcomplete.(cfnm);
                secsUse = secs;
                plot(secsUse,y,'Parent',app.hsub(c));
                title(cfnm,'Parent',app.hsub(c));
            end
            linkaxes(app.hsub,'x');
            % insert event table markers and link them
            app.ets = eventTable(idxStart,:);
            app.ete = eventTable(idxEnd,:);
            app.hpltStart = gobjects(sum(idxStart),4);
            app.hpltEnd = gobjects(sum(idxStart),4);
            cntStn = 1;
            cntM1 = 1;
            for i = 1:sum(idxStart)
                for c = 1:4
                    hsub = get(app.hsub(c));
                    ylims = hsub.YLim;
                    % start
                    xval = app.ets.UnixOffsetTime(i) + timeDiff;
                    startTime = xval;
                    hplt = plot([xval xval],ylims,'Parent',app.hsub(c),'Color',[0 0.8 0 0.7],'LineWidth',3);
                    app.hpltStart(i,c) = hplt;
                    hplt.ButtonDownFcn = @app.LineDown;
                    % end
                    xval = app.ete.UnixOffsetTime(i)+timeDiff;
                    endTime = xval;
                    hplt = plot([xval xval],ylims,'Parent',app.hsub(c),'Color',[0.8 0 0 0.7],'LineWidth',3);
                    app.hpltEnd(i,c) = hplt;
                    hplt.ButtonDownFcn = @app.LineDown;
                    
                    % get raw data
                    cfnm = sprintf('key%d',c-1);
                    y = outdatcomplete.(cfnm);
                    secsUse = secs;
                    idxuse = secsUse > startTime & secsUse < endTime;
                    % get sample rate
                    % xx - since this is stim sweep assume setting always
                    % the same 
                    idxElectrodes = 1; 
                    sr = str2num(strrep(outRec(idxElectrodes).tdData(c).sampleRate,'Hz',''));
                    if c <=2
                        % save raw data in order to plot psds
                        app.rawDatSTN(cntStn).rawdata = y(idxuse);
                        app.rawDatSTN(cntStn).sr = sr;
                        app.rawDatSTN(cntStn).chan = sprintf('+%s-%s',outRec(idxElectrodes).tdData(c).plusInput,outRec(idxElectrodes).tdData(c).minusInput);
                        app.rawDatSTN(cntStn).chanFullStr = outRec(idxElectrodes).tdData(c).chanFullStr;
                        cntStn = cntStn + 1;
                    else
                        % save raw data in order to plot psds
                        app.rawDatM1(cntM1).rawdata = y(idxuse);
                        app.rawDatM1(cntM1).sr = sr;
                        app.rawDatM1(cntM1).chan = sprintf('+%s-%s',outRec(idxElectrodes).tdData(c).plusInput,outRec(idxElectrodes).tdData(c).minusInput);
                        app.rawDatM1(cntM1).chanFullStr = outRec(idxElectrodes).tdData(c).chanFullStr;
                        cntM1 = cntM1 + 1;
                    end
                end
                linkprop(app.hpltStart(i,:),'XData');
                linkprop(app.hpltEnd(i,:),'XData');
            end
        end

        % Button pushed function: zoomButton
        function zoomButtonPushed(app, event)
            idx = app.montagefileDropDown.Value;
            hsub = get(app.hsub(1));
            xstart = (app.ets.UnixOffsetTime(idx) - app.subTime) - seconds(5);
            xend = (app.ete.UnixOffsetTime(idx) - app.subTime) + seconds(5);
            hsubs = app.dataplotsPanel.Children;
            hsubs(1).XLim = [xstart xend];
            
        end

        % Button pushed function: zoomcurrentButton
        function zoomcurrentButtonPushed(app, event)
            idx = app.montagefileDropDown.Value;
            xlims = [app.hpltStart(idx,1).XData(1) - seconds(5) app.hpltEnd(idx,1).XData(1) + seconds(5)];
            hsubs = app.dataplotsPanel.Children;
            hsubs(1).XLim = xlims;
            for h = 1:length(hsubs)
                lines = get(hsubs(h),'Children');
                hLine = lines(end);
                idxuse = hLine.XData > xlims(1) & hLine.XData < xlims(2);
                miny = min(hLine.YData(idxuse));
                maxy = max(hLine.YData(idxuse));
                hsubs(h).YLim = [miny maxy];
            end
        end

        % Button pushed function: plotpsdsButton
        function plotpsdsButtonPushed(app, event)
            
            % plot data in the psd stn
            delete(app.hStn)
            app.hStn(1) = subplot(1,1,1,'Parent',app.PlotdataPanelSTN);
            hold(app.hStn(1),'on')
            for m = 1:length(app.rawDatSTN)
                y = app.rawDatSTN(m).rawdata - mean(app.rawDatSTN(m).rawdata);
                srate = app.rawDatSTN(m).sr;
                [fftOut,f]   = pwelch(y,srate,srate/2,0:1:srate/2,srate,'psd');
                app.hpltSTNlines(m) = plot(f,log10(fftOut),'LineWidth',3,'Parent',app.hStn);
            end
            legend(app.hpltSTNlines,{app.rawDatSTN.chan});
            % populdate controller
            app.psdElectrodeSelector.Items = {app.rawDatSTN.chan};
            app.psdElectrodeSelector.ItemsData= 1:length({app.rawDatSTN.chan});
            app.psdElectrodeSelector.Multiselect = 'on';
            app.psdElectrodeSelector.Value = 1:length({app.rawDatSTN.chan});
            set(app.hStn(1),'FontSize',16);
            
            % plot data in the psd m1
            delete(app.hM1)
            app.hM1(1) = subplot(1,1,1,'Parent',app.PlotdataPanelM1);
            hold(app.hM1(1),'on')
            for m = 1:length(app.rawDatSTN)
                y = app.rawDatM1(m).rawdata - mean(app.rawDatM1(m).rawdata);
                srate = app.rawDatM1(m).sr;
                [fftOut,f]   = pwelch(y,srate,srate/2,0:1:srate/2,srate,'psd');
                app.hpltM1lines(m) = plot(f,log10(fftOut),'LineWidth',3,'Parent',app.hM1);
            end
            legend(app.hpltM1lines,{app.rawDatM1.chan});
            % populdate controller
            app.m1MontageSelector.Items = {app.rawDatM1.chan};
            app.m1MontageSelector.ItemsData= 1:length({app.rawDatM1.chan});
            app.m1MontageSelector.Multiselect = 'on';
            app.m1MontageSelector.Value = 1:length({app.rawDatM1.chan});
            
            set(app.hM1(1),'FontSize',16);
        end

        % Value changed function: psdElectrodeSelector
        function psdElectrodeSelectorValueChanged(app, event)
            value = app.psdElectrodeSelector.Value;
            hplt = get(app.hpltSTNlines);
            for h = 1:length(hplt)
                set(app.hpltSTNlines(h),'Visible','off')
            end
            for v = 1:length(value)
                set(app.hpltSTNlines(value(v)),'Visible','on')
            end
            legtext = {app.rawDatSTN.chan};
            legend(app.hpltSTNlines(value),legtext(value));
        end

        % Value changed function: m1MontageSelector
        function m1MontageSelectorValueChanged(app, event)
            value = app.m1MontageSelector.Value;
            hplt = get(app.hpltM1lines);
            for h = 1:length(hplt)
                set(app.hpltM1lines(h),'Visible','off')
            end
            for v = 1:length(value)
                set(app.hpltM1lines(value(v)),'Visible','on')
            end
            legtext = {app.rawDatM1.chan};
            legend(app.hpltM1lines(value),legtext(value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.selectdataTab = uitab(app.TabGroup);
            app.selectdataTab.Title = 'select data';
            app.loaddataButton = uibutton(app.selectdataTab, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.saveidxsButton = uibutton(app.selectdataTab, 'push');
            app.saveidxsButton.Text = 'save idxs';
            app.dataplotsPanel = uipanel(app.selectdataTab);
            app.dataplotsPanel.Title = 'data plots';
            app.loadidxsButton = uibutton(app.selectdataTab, 'push');
            app.loadidxsButton.Text = 'load idxs';
            app.montagefileDropDownLabel = uilabel(app.selectdataTab);
            app.montagefileDropDownLabel.Text = 'montage file';
            app.montagefileDropDown = uidropdown(app.selectdataTab);
            app.zoomButton = uibutton(app.selectdataTab, 'push');
            app.zoomButton.ButtonPushedFcn = createCallbackFcn(app, @zoomButtonPushed, true);
            app.zoomButton.Text = 'zoom';
            app.zoomcurrentButton = uibutton(app.selectdataTab, 'push');
            app.zoomcurrentButton.ButtonPushedFcn = createCallbackFcn(app, @zoomcurrentButtonPushed, true);
            app.zoomcurrentButton.Text = 'zoom current';
            app.plotpsdsButton = uibutton(app.selectdataTab, 'push');
            app.plotpsdsButton.ButtonPushedFcn = createCallbackFcn(app, @plotpsdsButtonPushed, true);
            app.plotpsdsButton.Text = 'plot psds';
            app.PSDSTNTab = uitab(app.TabGroup);
            app.PSDSTNTab.Title = 'PSD STN';
            app.PlotdataPanelSTN = uipanel(app.PSDSTNTab);
            app.PlotdataPanelSTN.Title = 'Plot data';
            app.electrodeLabel = uilabel(app.PSDSTNTab);
            app.electrodeLabel.Text = 'electrode';
            app.psdElectrodeSelector = uilistbox(app.PSDSTNTab);
            app.psdElectrodeSelector.ValueChangedFcn = createCallbackFcn(app, @psdElectrodeSelectorValueChanged, true);
            app.stimampLabel = uilabel(app.PSDSTNTab);
            app.stimampLabel.Text = 'stim amp';
            app.psdAmpSelector = uilistbox(app.PSDSTNTab);
            app.stimrateLabel = uilabel(app.PSDSTNTab);
            app.stimrateLabel.Text = 'stim rate';
            app.psdRateSelector = uilistbox(app.PSDSTNTab);
            app.PSDM1Tab = uitab(app.TabGroup);
            app.PSDM1Tab.Title = 'PSD M1';
            app.PlotdataPanelM1 = uipanel(app.PSDM1Tab);
            app.PlotdataPanelM1.Title = 'Plot data';
            app.montageListBoxLabel = uilabel(app.PSDM1Tab);
            app.montageListBoxLabel.Text = 'montage';
            app.m1MontageSelector = uilistbox(app.PSDM1Tab);
            app.m1MontageSelector.ValueChangedFcn = createCallbackFcn(app, @m1MontageSelectorValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_stim_sweep_data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end