classdef rcsDataChopper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RCSDataChopperUIFigure  matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        EventViewTab            matlab.ui.container.Tab
        loaddatadirButton       matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        filestartLabel          matlab.ui.control.Label
        fileStartReport         matlab.ui.control.Label
        fileendLabel            matlab.ui.control.Label
        fileEndReport           matlab.ui.control.Label
        durationLabel           matlab.ui.control.Label
        fileDurationReport      matlab.ui.control.Label
        DataViewTab             matlab.ui.container.Tab
        FromEditFieldLabel      matlab.ui.control.Label
        FromEditField           matlab.ui.control.EditField
        ToEditFieldLabel        matlab.ui.control.Label
        ToEditField             matlab.ui.control.EditField
        saveButton              matlab.ui.control.Button
        savenameEditFieldLabel  matlab.ui.control.Label
        savenameEditField       matlab.ui.control.EditField
        DataPlot                matlab.ui.container.TabGroup
        RawDataTab              matlab.ui.container.Tab
        popoutRawData           matlab.ui.control.Button
        eventTableForViewer     matlab.ui.control.Table
        PSDTab                  matlab.ui.container.Tab
        popOutPSD               matlab.ui.control.Button
        clearPSD                matlab.ui.control.Button
        UITable2                matlab.ui.control.Table
        SpectrogramTab          matlab.ui.container.Tab
        popOutSpectrakData      matlab.ui.control.Button
        zoomoutButton           matlab.ui.control.Button
        plotspectrogramButton   matlab.ui.control.Button
        lblstructDropDownLabel  matlab.ui.control.Label
        lblstructDropDown       matlab.ui.control.DropDown
        printlablestrucButton   matlab.ui.control.Button
        plotPSDButton           matlab.ui.control.Button
        setlimitsButton         matlab.ui.control.StateButton
        showsavedsetsButton     matlab.ui.control.StateButton
        plotselectedsetsButton  matlab.ui.control.Button
        searchallchoppedButton  matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        hfig % handle to punched out figure
        diruse % Description
        outdatcomplete % raw data
        outRec % device time domain data
        eventTable % event table data
        displayTable % display table data
        limitFrom % what to draw limits from (datetime)
        limitTo % what to draw limits to (datetime)
        fileStart % Description
        fileEnd % Description
        lblStrucIdx % idx of lsbel structure
        tdDat % table of time domain data
        patientStr % patient name
        sideStr % patient side
        outdatcompleteAcc % Description
        powerOut % power data
        adaptiveTable % adaptive data
        savedSetsLoaded % inidicator variable to show if saved sets were loaded
    end
    
    methods (Access = public)
        
        function updateFig(app)
            %% load data
            [app.outdatcomplete,app.outRec,app.eventTable,app.outdatcompleteAcc,app.powerOut,app.adaptiveTable] =  ...
                MAIN_load_rcs_data_from_folder(app.diruse);
            app.eventTable = allign_events_time_domain_time(app.eventTable,app.outdatcomplete);
            % get power data
            % get power data in time domain
            % get times for power data
            timestamps   = app.powerOut.powerTable.timestamp;
            systemTicks  = app.powerOut.powerTable.systemTick;
            rxUnixTimes  = app.powerOut.powerTable.PacketRxUnixTime;
            
            timesOut = getTimesFromPowerOrAdaptive(timestamps,systemTicks,rxUnixTimes);
            app.powerOut.powerTable = app.powerOut.powerTable(2:end,:); % get rid of first idx
            app.powerOut.powerTable.derivedTimes = timesOut;
            
            %
            nm = app.RCSDataChopperUIFigure.Name;
            [pn,fn] = fileparts(app.diruse);
            [pn,fn] = fileparts(pn);
            [pn,patientraw] = fileparts(pn);
            app.patientStr = patientraw(1:end-1);
            app.sideStr = patientraw(end);
            t = app.eventTable.HostUnixTime(1);
            t.Format = 'dd-MMM-yyyy HH:mm';
            appTitle = sprintf('%s %s %s session - %s',app.patientStr,app.sideStr,t,app.eventTable.sessionid{1});
            app.RCSDataChopperUIFigure.Name = appTitle;
            %% populate table
            app.displayTable = app.eventTable(:,{'insTimes','EventSubType','EventType'});
            app.UITable.Data = app.displayTable;
            app.UITable.ColumnName = app.displayTable.Properties.VariableNames;
            
            %%% populate the new table up %%%%
            app.eventTableForViewer.Data = app.eventTable(:,{'insTimes','EventSubType','EventType'});
            app.eventTableForViewer.UserData = app;
            app.eventTableForViewer.ColumnName = app.eventTable.Properties.VariableNames;
            app.eventTableForViewer.CellSelectionCallback =  @setLimitsFromTable;
            %% populate drop down menu
            app.tdDat = struct2table(app.outRec,'asarray',true);
            app.lblstructDropDown.Items = cellstr(datestr(app.tdDat.timeStart));
            %% populate time domain data
            startTime = app.outdatcomplete.derivedTimes(1);
            endTime   = app.outdatcomplete.derivedTimes(end);
            app.fileStart = datestr(startTime);
            app.fileEnd = datestr(endTime);
            app.fileStartReport.Text =  datestr(startTime);
            app.fileEndReport.Text =  datestr(endTime);
            struse = sprintf('%s (hh:mm.ss)',datestr(endTime- startTime,'HH:MM.SS'));
            app.fileDurationReport.Text = struse;
            etyps = app.displayTable.EventSubType;
            if ~iscell(etyps)
                etyps = {etyps};
            end
            etyps(cellfun(@(x) ~ischar(x),etyps)) = ''; % in case not all strings and have doubles
            % set up limits to include all data initially
            app.FromEditField.Value = app.fileStart;
            app.ToEditField.Value = app.fileEnd;
            
            function setLimitsFromTable(source,event)
                if size(event.Indices,1) ==1
                    app.FromEditField.Value =  sprintf('%s',source.Data.insTimes(event.Indices(1,1),1));
                    updatePlot(app);
                else
                    app.FromEditField.Value =  sprintf('%s',source.Data.insTimes(event.Indices(1,1),1));
                    app.ToEditField.Value =  sprintf('%s',source.Data.insTimes(event.Indices(end,1),1));
                    updatePlot(app);
                end
            end
        end


        function updatePlot(app)
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 5;
            timeStart = datetime(app.FromEditField.Value,'Format','dd-MMM-yyyy HH:mm:ss.SSS');
            timeStop = datetime(app.ToEditField.Value,'Format','dd-MMM-yyyy HH:mm:ss.SSS');
            if timeStop < timeStart
                timeStop = timeStart + minutes(1);
            end
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            timesuse = app.outdatcomplete.derivedTimes(idxuse);
            val = app.lblstructDropDown.Value ;
            itms = app.lblstructDropDown.Items;
            outrcidx = find(cellfun(@(x) strcmp(val,x),itms)==1);
            for i = 1:length(app.RawDataTab.Children)
                if strcmp(app.RawDataTab.Children(i).Type,'axes')
                    cla(app.RawDataTab.Children(i));
                end
            end
            for c = 1:4
                hsub(c) = subplot(numplot,1,c,'Parent',app.RawDataTab);
                fnm = sprintf('key%d',c-1);
                y = app.outdatcomplete.(fnm)(idxuse);
                y = y - mean(y);
                plot(hsub(c),timesuse,y);
                title(hsub(c),app.outRec(outrcidx).tdData(c).chanFullStr);
            end
            hsub(5) = subplot(numplot,1,5,'Parent',app.RawDataTab);
            hold(hsub(5),'on');
            timesAcc = app.outdatcompleteAcc.derivedTimes;
            idxuseAcc = timesAcc > timeStart  & timesAcc < timeStop;
            timesuseAcc = app.outdatcompleteAcc.derivedTimes(idxuseAcc);
            x = app.outdatcompleteAcc.XSamples(idxuseAcc);
            y = app.outdatcompleteAcc.YSamples(idxuseAcc);
            z = app.outdatcompleteAcc.ZSamples(idxuseAcc);
            
            plot(hsub(5),timesuseAcc,x-mean(x));
            plot(hsub(5),timesuseAcc,y-mean(y));
            plot(hsub(5),timesuseAcc,z-mean(z));
            title(hsub(5),'actigraphy');
            linkaxes(hsub,'x')
            xlim(hsub(5),[timeStart timeStop]);


        end
        
        function plotSpectrogram(app)
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 4;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            times = app.outdatcomplete.derivedTimes(idxuse);
            if length(unique(app.outdatcomplete.samplerate(idxuse))) == 1
                srate = unique(app.outdatcomplete.samplerate(idxuse));
            else
                error('the segment you are selecting has more than one smapling rate')
            end
            for c = 1:4
                hsub(c) = subplot(4,1,c,'Parent',app.SpectrogramTab);
                fnm = sprintf('key%d',c-1);
                y = app.outdatcomplete.(fnm)(idxuse);
                y = y - mean(y);
                %                 [s,f,t,p] = spectrogram(y,srate,ceil(0.8750*srate),1:120,srate,...
                %                     'yaxis','power');
                %                 [s, f , t, p ] = spectrogram(y,blackmanharris(256),220,512,srate,'yaxis','power');
                [s,f,t,p] = spectrogram(y,kaiser(256,5),220,512,srate,'yaxis');
                
                surf(hsub(c),seconds(t), f, 10*log10(p), 'EdgeColor', 'none');
                colormap(hsub(c),jet)
                shading(hsub(c),'interp');
                view(hsub(c),2);
                axis(hsub(c),'tight');
                xlabel(hsub(c),'seconds');
                ylabel(hsub(c),'Frequency (Hz)');
                title(hsub(c),app.outRec(1).tdData(c).chanFullStr);
            end
            
        end
        
        function plotPSD(app)
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 4;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            times = app.outdatcomplete.derivedTimes(idxuse);
            
            if length(app.outRec) == 1
                idxuseDeviceSettings = 1;
            else
                startTimesDeviceSettings = [app.outRec.timeStart]';
                endTimesTimesDeviceSettings = [app.outRec.timeEnd]';
                idxuseDeviceSettings = find(isbetween(timeStart,...
                    startTimesDeviceSettings,endTimesTimesDeviceSettings)==1);
                % could not find device settings idx, probably between calls
                if isempty(idxuseDeviceSettings)
                    idxuseDeviceSettings = find(timeStart < endTimesTimesDeviceSettings,1);
                end
                
            end
            
            %             axesHandles = app.PSDTab.Children(ishghandle(app.PSDTab.Children,'axes'));
            
            for c = 1:4
                hsub(c) = subplot(2,2,c,'Parent',app.PSDTab);
                hold(hsub(c),'on');
                fnm = sprintf('key%d',c-1);
                y = app.outdatcomplete.(fnm)(idxuse);
                y = y - mean(y);
                if length(unique(app.outdatcomplete.samplerate(idxuse))) == 1
                    srate = unique(app.outdatcomplete.samplerate(idxuse));
                else
                    error('the segment you are selecting has more than one smapling rate')
                end
                [fftOut,f]   = pwelch(y,srate,srate/2,0:1:srate/2,srate,'psd');
                hplt = plot(hsub(c),f,log10(fftOut));
                hplt.LineWidth = 2;
                
                xlabel(hsub(c),'Frequency (Hz)');
                ylabel(hsub(c),'Power (log_1_0\mV^2/Hz)');
                title(hsub(c),app.outRec(idxuseDeviceSettings).tdData(c).chanFullStr);
            end
        end
        
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            var = varargin{1};
            if isstruct(var)
                params = var;
                if ~isempty(params)
                    app.diruse = params.dir;
                    updateFig(app);
                    updatePlot(app);
                end
            else
                app.diruse = varargin{1};
                updateFig(app);
                updatePlot(app);
            end
        end

        % Callback function
        function FromDropDownValueChanged(app, event)
            value = app.FromDropDown.Value;
            eventidx = app.FromDropDown.Value;
            disTab = app.displayTable;
            app.limitFrom = disTab.UnixOnsetTime(eventidx);
            app.FromEditField.Value = datestr(app.limitFrom);
            updatePlot(app);
            
        end

        % Callback function
        function ToDropDownValueChanged(app, event)
            value = app.ToDropDown.Value;
            eventidx = app.ToDropDown.Value;
            disTab = app.displayTable;
            app.limitTo = disTab.UnixOnsetTime(eventidx);
            app.ToEditField.Value = datestr(app.limitTo);
            updatePlot(app);
        end

        % Button pushed function: zoomoutButton
        function zoomoutButtonPushed(app, event)
            app.ToEditField.Value = app.fileEnd;
            app.FromEditField.Value = app.fileStart;
            updatePlot(app);
        end

        % Value changed function: ToEditField
        function ToEditFieldValueChanged(app, event)
            value = app.ToEditField.Value;
            updatePlot(app);
        end

        % Value changed function: FromEditField
        function FromEditFieldValueChanged(app, event)
            value = app.FromEditField.Value;
            updatePlot(app);
        end

        % Button pushed function: plotspectrogramButton
        function plotspectrogramButtonPushed(app, event)
            plotSpectrogram(app);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            times = app.outdatcomplete.derivedTimes;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            outdatachunk = app.outdatcomplete(idxuse,:);
            eventTable = app.eventTable;
            outRec = app.outRec;
            fnm = sprintf('%s.mat',app.savenameEditField.Value);
            svname = fullfile(app.diruse,fnm);
            if ~isempty(app.powerOut.powerTable) % if there is data in power table - save this chunk too
                timespower = app.powerOut.powerTable.derivedTimes;
                
                idxuse = timespower > timeStart  & timespower < timeStop;
                powerChunk = app.powerOut.powerTable(idxuse,:);
                powerMeta = app.powerOut.bands;
                save(svname,'outdatachunk','eventTable','outRec','powerChunk','powerMeta');
            else
                save(svname,'outdatachunk','eventTable','outRec');
                
            end
            
        end

        % Button pushed function: loaddatadirButton
        function loaddatadirButtonPushed(app, event)
            app.diruse = uigetdir('choose dir with .jsons');
            updateFig(app);
            updatePlot(app);
        end

        % Button pushed function: printlablestrucButton
        function printlablestrucButtonPushed(app, event)
            struct2table(app.outRec)
        end

        % Value changed function: lblstructDropDown
        function lblstructDropDownValueChanged(app, event)
            value = app.lblstructDropDown.Value;
            updatePlot(app);
        end

        % Button pushed function: popoutRawData
        function popoutRawDataButtonPushed(app, event)
            hfig = figure;
            hfig.Color = 'w';
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 5;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            timesuse = app.outdatcomplete.derivedTimes(idxuse);
            val = app.lblstructDropDown.Value ;
            itms = app.lblstructDropDown.Items;
            outrcidx = find(cellfun(@(x) strcmp(val,x),itms)==1);
            for c = 1:4
                hsub(c) = subplot(numplot,1,c,'Parent',hfig);
                fnm = sprintf('key%d',c-1);
                y = app.outdatcomplete.(fnm)(idxuse);
                y = y - mean(y);
                plot(hsub(c),timesuse,y);
                title(hsub(c),app.outRec(outrcidx).tdData(c).chanFullStr);
            end
            hsub(5) = subplot(numplot,1,5,'Parent',hfig);
            hold(hsub(5),'on');
            timesAcc = app.outdatcompleteAcc.derivedTimes;
            idxuseAcc = timesAcc > timeStart  & timesAcc < timeStop;
            timesuseAcc = app.outdatcompleteAcc.derivedTimes(idxuseAcc);
            x = app.outdatcompleteAcc.XSamples(idxuseAcc);
            y = app.outdatcompleteAcc.YSamples(idxuseAcc);
            z = app.outdatcompleteAcc.ZSamples(idxuseAcc);
            
            plot(hsub(5),timesuseAcc,x-mean(x));
            plot(hsub(5),timesuseAcc,y-mean(y));
            plot(hsub(5),timesuseAcc,z-mean(z));
            title(hsub(5),'actigraphy');
            
            linkaxes(hsub,'x');
        end

        % Button pushed function: popOutSpectrakData
        function popOutSpectrakDataButtonPushed(app, event)
            hfig = figure;
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 4;
            timeStart = datetime(app.FromEditField.Value);
            timeStop = datetime(app.ToEditField.Value);
            timeStart.TimeZone = times.TimeZone;
            timeStop.TimeZone = times.TimeZone;
            idxuse = times > timeStart  & times < timeStop;
            times = app.outdatcomplete.derivedTimes(idxuse);


            for c = 1:4
                hsub(c) = subplot(4,1,c,'Parent',hfig);
                fnm = sprintf('key%d',c-1);
                y = app.outdatcomplete.(fnm)(idxuse);
                y = y - mean(y);
                srate = str2num(strrep(app.outRec(1).tdData(c).sampleRate,'Hz',''));
                %                 [s,f,t,p] = spectrogram(y,srate,ceil(0.8750*srate),1:120,srate,...
                %                     'yaxis','power');
                
                [s,f,t,p] = spectrogram(y,kaiser(256,5),220,512,srate,'yaxis');
                colormap(jet)
                
                surf(hsub(c),seconds(t), f, 10*log10(p), 'EdgeColor', 'none');
                shading(hsub(c),'interp');
                view(hsub(c),2);
                axis(hsub(c),'tight');
                xlabel(hsub(c),'seconds');
                ylabel(hsub(c),'Frequency (Hz)');
                title(hsub(c),app.outRec(1).tdData(c).chanFullStr);
            end
            linkaxes(hsub,'x');
        end

        % Button pushed function: plotPSDButton
        function plotPSDButtonPushed(app, event)
            plotPSD(app);
        end

        % Button pushed function: popOutPSD
        function popOutPSDButtonPushed(app, event)
            hfig = figure;
            hfig.Color = 'w';
            set(0,'defaultAxesFontSize',16)
            times = app.outdatcomplete.derivedTimes;
            numplot = 4;
            idxsplot = app.UITable2.Data.plot;
            timesStart = app.UITable2.Data.timeStart(idxsplot);
            timesEnd = app.UITable2.Data.timeEnd(idxsplot);
            
            for  t = 1:length(timesStart)
                timeStart = timesStart(t);
                timeStop = timesEnd(t);
                timeStart.TimeZone = times.TimeZone;
                timeStop.TimeZone = times.TimeZone;
                idxuse = times > timeStart  & times < timeStop;
                if length(unique(app.outdatcomplete.samplerate(idxuse))) == 1
                    srate = unique(app.outdatcomplete.samplerate(idxuse));
                else
                    error('the segment you are selecting has more than one smapling rate')
                end
                for c = 1:4
                    hsub(c) = subplot(2,2,c,'Parent',hfig);
                    hold(hsub(c),'on');
                    fnm = sprintf('key%d',c-1);
                    y = app.outdatcomplete.(fnm)(idxuse);
                    y = y - mean(y);
                    [fftOut,f]   = pwelch(y,srate,srate/2,0:1:srate/2,srate,'psd');
                    hplt = plot(hsub(c),f,log10(fftOut));
                    hplt.LineWidth = 2;
                    
                    xlabel(hsub(c),'Frequency (Hz)');
                    ylabel(hsub(c),'Power (log_1_0\mV^2/Hz)');
                    title(hsub(c),app.outRec(1).tdData(c).chanFullStr);
                end
            end
            
            legendTtls = cellfun(@(x) strrep(x,'_',' '), app.UITable2.Data.name(idxsplot), 'UniformOutput', false);
            axesidxs = ishghandle( hfig.Children,'axes');
            hsubPlots = hfig.Children(axesidxs);
            for h = 1:length(hsubPlots)
                legend(hsubPlots(h),flipud(hsubPlots(h).Children),legendTtls);
            end
            
            linkaxes(hsub,'x');
        end

        % Value changed function: setlimitsButton
        function setlimitsButtonValueChanged(app, event)
            value = app.setlimitsButton.Value;
            if value
                app.eventTableForViewer.Visible = 'on';
            else
                app.eventTableForViewer.Visible = 'off';
            end
        end

        % Button pushed function: clearPSD
        function clearPSDButtonPushed(app, event)
            for i = 1:length(app.PSDTab.Children)
                if strcmp(app.PSDTab.Children(i).Type,'axes')
                    cla(app.PSDTab.Children(i));
                end
            end
        end

        % Value changed function: showsavedsetsButton
        function showsavedsetsButtonValueChanged(app, event)
            value = app.showsavedsetsButton.Value;
            if value == 1
                if isempty(app.UITable2.Data) || ~isempty(app.UITable2.Data)
                    if app.searchallchoppedButton.Value
                        dirname = app.diruse;
                        [pn,~] = fileparts(dirname);
                        [pn,~] = fileparts(pn);
                        dirname = pn;
                    else
                        dirname = app.diruse;
                    end
                    
                    matdirs = findFilesBVQX(dirname,'*.mat');
                    filesExlude = {'DeviceSettings',...
                        'EventLog',...
                        'RawDataAccel',...
                        'RawDataPower',...
                        'RawDataTD',...
                        'StimLog',...
                        'processedAccData',...
                        'processedTDdata',...
                        'database',...
                        'rawMontageData'};
                    idxdontuse = zeros(length(matdirs),1);
                    [pn,fn] = cellfun(@(x) fileparts(x),matdirs,'UniformOutput',false);
                    for f = 1:length(filesExlude)
                        idx = cellfun(@(x) strcmp(x,filesExlude{f}),fn);
                        idxdontuse(idx) = 1;
                    end
                    filesUse = matdirs(~idxdontuse);
                    [pn,fn] = cellfun(@(x) fileparts(x),filesUse,'UniformOutput',false);
                    
                    for f = 1:length(filesUse)
                        vars = [];
                        vars = whos('-file',filesUse{f});
                        if sum(strcmp({vars.name},'outdatachunk'))==1
                            idxkeep(f) = 1;
                        else
                            idxkeep(f) = 0;
                        end
                    end
                    filesUse = filesUse(logical(idxkeep),:);
                    fn = fn(logical(idxkeep));
                    
                    for f = 1:length(filesUse)
                        load(filesUse{f})
                        name{f} = fn{f};
                        timeStart(f) = outdatachunk.derivedTimes(1);
                        timeEnd(f) = outdatachunk.derivedTimes(end);
                        duration(f) = outdatachunk.derivedTimes(end) - outdatachunk.derivedTimes(1);
                        location{f} = filesUse{f};
                    end
                    plotChunk = false(f,1);
                    dataTable = table(name',timeStart',timeEnd',duration',plotChunk,location',...
                        'VariableNames',{'name','timeStart','timeEnd','duration','plot','location'});
                    dataTable.plot = logical(zeros(size(dataTable,1),1));
                    
                    dataTableDisplay = dataTable(:,{'name','timeStart','timeEnd','duration','plot'});
                    app.UITable2.Data = dataTableDisplay;
                    ncols = size(app.UITable2.Data,2);
                    
                    app.UITable2.ColumnEditable(4) = false;
                    app.UITable2.ColumnEditable(5) = true; %all columns editable
                    app.UITable2.UserData = dataTable;
                    app.UITable2.ColumnName = dataTableDisplay.Properties.VariableNames;
                    app.UITable2.Visible = 'on';
                    
                else
                    app.UITable2.Visible = 'on';
                end
            else
                app.UITable2.Visible = 'off';
            end
        end

        % Button pushed function: plotselectedsetsButton
        function plotselectedsetsButtonPushed(app, event)
            idxsplot = app.UITable2.Data.plot;
            dataPlot = app.UITable2.UserData(idxsplot,:);
            
            
            for t = 1:size(dataPlot,1)
                %%  load and plot eaceh set
                load(dataPlot.location{t},'outdatachunk');
                timeStart = dataPlot.timeStart(t);
                if length(app.outRec) == 1
                    idxuseDeviceSettings = 1;
                else
                    startTimesDeviceSettings = [app.outRec.timeStart]';
                    if isempty(app.outRec(end).timeEnd) % then end time is end of file
                        app.outRec(end).timeEnd = app.outdatcomplete.derivedTimes(end);
                    end
                    endTimesTimesDeviceSettings = [app.outRec.timeEnd]';
                    idxuseDeviceSettings = find(isbetween(timeStart,...
                        startTimesDeviceSettings,endTimesTimesDeviceSettings)==1);
                    % could not find device settings idx, probably between calls
                    if isempty(idxuseDeviceSettings)
                        idxuseDeviceSettings = find(timeStart < endTimesTimesDeviceSettings,1);
                    end
                    
                end
                for c = 1:4
                    hsub(c) = subplot(2,2,c,'Parent',app.PSDTab);
                    hold(hsub(c),'on');
                    fnm = sprintf('key%d',c-1);
                    y = outdatachunk.(fnm);
                    y = y - mean(y);
                    if length(unique(outdatachunk.samplerate)) == 1
                        srate = unique(outdatachunk.samplerate);
                    else
                        error('the segment you are selecting has more than one smapling rate')
                    end
                    [fftOut,f]   = pwelch(y,srate,srate/2,0:1:srate/2,srate,'psd');
                    hplt = plot(hsub(c),f,log10(fftOut));
                    hplt.LineWidth = 2;
                    
                    xlabel(hsub(c),'Frequency (Hz)');
                    ylabel(hsub(c),'Power (log_1_0\mV^2/Hz)');
                    title(hsub(c),app.outRec(idxuseDeviceSettings).tdData(c).chanFullStr);
                end
                %%
                
                
            end
            legendTtls = cellfun(@(x) strrep(x,'_',' '), app.UITable2.Data.name(idxsplot), 'UniformOutput', false);
            axesidxs = ishghandle( app.PSDTab.Children,'axes');
            hsubPlots = app.PSDTab.Children(axesidxs);
            for h = 1:length(hsubPlots)
                legend(hsubPlots(h),flipud(hsubPlots(h).Children),legendTtls);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RCSDataChopperUIFigure = uifigure('Visible', 'off');
            app.RCSDataChopperUIFigure.Name = 'RC+S Data Chopper';
            app.TabGroup = uitabgroup(app.RCSDataChopperUIFigure);
            app.EventViewTab = uitab(app.TabGroup);
            app.EventViewTab.Title = 'Event View';
            app.loaddatadirButton = uibutton(app.EventViewTab, 'push');
            app.loaddatadirButton.ButtonPushedFcn = createCallbackFcn(app, @loaddatadirButtonPushed, true);
            app.loaddatadirButton.Tooltip = {'load data directory with .json files'};
            app.loaddatadirButton.Text = 'load data dir';
            app.UITable = uitable(app.EventViewTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.filestartLabel = uilabel(app.EventViewTab);
            app.filestartLabel.Text = 'file start:';
            app.fileStartReport = uilabel(app.EventViewTab);
            app.fileStartReport.Text = '00';
            app.fileendLabel = uilabel(app.EventViewTab);
            app.fileendLabel.Text = 'file end:';
            app.fileEndReport = uilabel(app.EventViewTab);
            app.fileEndReport.Text = '00';
            app.durationLabel = uilabel(app.EventViewTab);
            app.durationLabel.Text = 'duration:';
            app.fileDurationReport = uilabel(app.EventViewTab);
            app.fileDurationReport.Text = '00';
            app.DataViewTab = uitab(app.TabGroup);
            app.DataViewTab.Title = 'Data View';
            app.FromEditFieldLabel = uilabel(app.DataViewTab);
            app.FromEditFieldLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.DataViewTab, 'text');
            app.FromEditField.ValueChangedFcn = createCallbackFcn(app, @FromEditFieldValueChanged, true);
            app.ToEditFieldLabel = uilabel(app.DataViewTab);
            app.ToEditFieldLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.DataViewTab, 'text');
            app.ToEditField.ValueChangedFcn = createCallbackFcn(app, @ToEditFieldValueChanged, true);
            app.ToEditField.Tooltip = {'Change this value to adjust limits in all channels'};
            app.saveButton = uibutton(app.DataViewTab, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.savenameEditFieldLabel = uilabel(app.DataViewTab);
            app.savenameEditFieldLabel.Text = 'save name';
            app.savenameEditField = uieditfield(app.DataViewTab, 'text');
            app.DataPlot = uitabgroup(app.DataViewTab);
            app.RawDataTab = uitab(app.DataPlot);
            app.RawDataTab.Title = 'Raw Data';
            app.popoutRawData = uibutton(app.RawDataTab, 'push');
            app.popoutRawData.ButtonPushedFcn = createCallbackFcn(app, @popoutRawDataButtonPushed, true);
            app.popoutRawData.Text = 'pop out';
            app.eventTableForViewer = uitable(app.RawDataTab);
            app.eventTableForViewer.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.eventTableForViewer.RowName = {};
            app.eventTableForViewer.Visible = 'off';
            app.PSDTab = uitab(app.DataPlot);
            app.PSDTab.Title = 'PSD';
            app.popOutPSD = uibutton(app.PSDTab, 'push');
            app.popOutPSD.ButtonPushedFcn = createCallbackFcn(app, @popOutPSDButtonPushed, true);
            app.popOutPSD.Text = 'pop out';
            app.clearPSD = uibutton(app.PSDTab, 'push');
            app.clearPSD.ButtonPushedFcn = createCallbackFcn(app, @clearPSDButtonPushed, true);
            app.clearPSD.Text = 'clear';
            app.UITable2 = uitable(app.PSDTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.UITable2.Visible = 'off';
            app.SpectrogramTab = uitab(app.DataPlot);
            app.SpectrogramTab.Title = 'Spectrogram';
            app.popOutSpectrakData = uibutton(app.SpectrogramTab, 'push');
            app.popOutSpectrakData.ButtonPushedFcn = createCallbackFcn(app, @popOutSpectrakDataButtonPushed, true);
            app.popOutSpectrakData.Text = 'pop out';
            app.zoomoutButton = uibutton(app.DataViewTab, 'push');
            app.zoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @zoomoutButtonPushed, true);
            app.zoomoutButton.Text = 'zoom out';
            app.plotspectrogramButton = uibutton(app.DataViewTab, 'push');
            app.plotspectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @plotspectrogramButtonPushed, true);
            app.plotspectrogramButton.Text = 'plot spectrogram';
            app.lblstructDropDownLabel = uilabel(app.DataViewTab);
            app.lblstructDropDownLabel.Text = 'lbl struct';
            app.lblstructDropDown = uidropdown(app.DataViewTab);
            app.lblstructDropDown.ValueChangedFcn = createCallbackFcn(app, @lblstructDropDownValueChanged, true);
            app.printlablestrucButton = uibutton(app.DataViewTab, 'push');
            app.printlablestrucButton.ButtonPushedFcn = createCallbackFcn(app, @printlablestrucButtonPushed, true);
            app.printlablestrucButton.Text = 'print lable struc';
            app.plotPSDButton = uibutton(app.DataViewTab, 'push');
            app.plotPSDButton.ButtonPushedFcn = createCallbackFcn(app, @plotPSDButtonPushed, true);
            app.plotPSDButton.Text = 'plot PSD';
            app.setlimitsButton = uibutton(app.DataViewTab, 'state');
            app.setlimitsButton.ValueChangedFcn = createCallbackFcn(app, @setlimitsButtonValueChanged, true);
            app.setlimitsButton.Text = 'set limits';
            app.showsavedsetsButton = uibutton(app.DataViewTab, 'state');
            app.showsavedsetsButton.ValueChangedFcn = createCallbackFcn(app, @showsavedsetsButtonValueChanged, true);
            app.showsavedsetsButton.Text = 'show saved sets';
            app.plotselectedsetsButton = uibutton(app.DataViewTab, 'push');
            app.plotselectedsetsButton.ButtonPushedFcn = createCallbackFcn(app, @plotselectedsetsButtonPushed, true);
            app.plotselectedsetsButton.Text = 'plot selected sets';
            app.searchallchoppedButton = uibutton(app.DataViewTab, 'state');
            app.searchallchoppedButton.Tooltip = {'If you want to compare data chunks from across a few runs'; ' this will allow you search all sessions in the directory above for chopped data'};
            app.searchallchoppedButton.Text = 'search all chopped';
            % Show the figure after all components are created
            app.RCSDataChopperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rcsDataChopper(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RCSDataChopperUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RCSDataChopperUIFigure)
        end
    end
end