classdef rcsSessionViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        sessionsTab    matlab.ui.container.Tab
        UITable        matlab.ui.control.Table
        loaddirButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        rootdir % Description
    end
    
    methods (Access = public)
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.UITable.Parent = app.sessionsTab;
            pos = app.UITable.Position;
            delete(app.UITable);
            app.UITable = uitable(app.sessionsTab);
            app.UITable.Position = pos;
            app.UITable.Visible = 'on';
            app.UITable.Enable = 'on';
            if ~isempty(varargin)
                app.rootdir = varargin{1};
                app.UITable.Visible = 'off';
                tblout = getDataBaseRCSdata(app.rootdir);
                tbluse = tblout(:,{'sessname','rectime','startTime','endTime','duration'});
                % print the entire structure to the command line
                clc;
                for s = 1:size(tblout,1)
                    if ~isempty(tbluse.duration{s})
                        fprintf('start time: %s \t end time: %s \t duration %s \t\n',...
                            tblout.startTime{s},tblout.endTime{s},tblout.duration{s});
                    else
                        fprintf('\t\tsession %s is empty\n',...
                            tblout.sessname{s});
                    end
                end
                idxuse  = cellfun(@(x)  ~isempty(x),tbluse.duration);
                tbluse = tbluse(idxuse,:);
                uiwait(app.UIFigure,1);
                set(app.UITable, 'Data', cell(size(get(app.UITable,'Data'))));
                set(app.UITable, 'Data', tbluse);
                app.UITable.Visible = 'on';
                app.UITable.Enable = 'on';
                app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            end
        end

        % Button pushed function: loaddirButton
        function loaddirButtonPushed(app, event)
            app.rootdir = uigetdir(pwd,'dir with session files');
            if app.rootdir == 0 % user clicked cancel
            else
                app.UITable.Visible = 'off';
                tblout = getDataBaseRCSdata(app.rootdir);
                tbluse = tblout(:,{'sessname','rectime','startTime','endTime','duration'});
                % print the entire structure to the command line
                clc;
                for s = 1:size(tblout,1)
                    if ~isempty(tbluse.duration{s})
                        fprintf('start time: %s \t end time: %s \t duration %s \t\n',...
                            tblout.startTime{s},tblout.endTime{s},tblout.duration{s});
                    else
                        fprintf('\t\tsession %s is empty\n',...
                            tblout.sessname{s});
                    end
                end
                idxuse  = cellfun(@(x)  ~isempty(x),tbluse.duration);
                tbluse = tbluse(idxuse,:);
                uiwait(app.UIFigure,1);
                set(app.UITable, 'Data', cell(size(get(app.UITable,'Data'))));
                set(app.UITable, 'Data', tbluse);
                app.UITable.Visible = 'on';
                app.UITable.Enable = 'on';
                app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            end
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            sessdir = app.UITable.Data(indices(1,1),:).sessname{:};
            ff = findFilesBVQX(fullfile(app.rootdir,sessdir),'Device*',struct('dirs',1));
            if isempty(ff)
                warning('could not find any data in this session');
            else
                diruse = ff{1};
                params.dir = diruse;
                rcsDataChopper(params);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.sessionsTab = uitab(app.TabGroup);
            app.sessionsTab.Title = 'sessions';
            app.UITable = uitable(app.sessionsTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.loaddirButton = uibutton(app.sessionsTab, 'push');
            app.loaddirButton.ButtonPushedFcn = createCallbackFcn(app, @loaddirButtonPushed, true);
            app.loaddirButton.Text = 'load dir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rcsSessionViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end