classdef rcsViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        FilesTab                  matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        loaddirButton             matlab.ui.control.Button
        UITableRecs               matlab.ui.control.Table
        sessionsLabel             matlab.ui.control.Label
        recordinginsessionsLabel  matlab.ui.control.Label
        RawdataTab                matlab.ui.container.Tab
        PSDButton                 matlab.ui.control.StateButton
        startEditFieldLabel       matlab.ui.control.Label
        startEditField            matlab.ui.control.NumericEditField
        endEditFieldLabel         matlab.ui.control.Label
        endEditField              matlab.ui.control.NumericEditField
        RawButton                 matlab.ui.control.StateButton
        SpectrogramButton         matlab.ui.control.StateButton
        SpectrogramTab            matlab.ui.container.Tab
    end

    
    properties (Access = public)
        hsb % handles for all graphs in the plot
        srate % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RawdataTab.HandleVisibility = 'off';
            x =2;
        end

        % Button pushed function: loaddirButton
        function loaddirButtonPushed(app, event)
            dirname = uigetdir(pwd,'dir with session files');
            tblout = getDataBaseRCSdata(dirname);
            app.UITable.Data = tblout;
            app.UITable.ColumnName = tblout.Properties.VariableNames;
            app.UITable.ColumnEditable = true;
        end

        % Cell edit callback: UITable
        function plotRCSfile(app, event)
            
            idxPlot = event.Indices(1,1);
            % load recordings table
            [pn,fn] = fileparts(app.UITable.Data.tdfile{idxPlot});
            ff = findFilesBVQX(pn,'DeviceSettings.json');
            recordings = loadDeviceSettings(ff{1});
            recordingsNew = [recordings,   table(logical(zeros(size(recordings,1),1)))];
            recordingsNew.Properties.VariableNames{end} = 'plot';
            app.UITableRecs.Data = recordingsNew;
            app.UITableRecs.ColumnName = recordingsNew.Properties.VariableNames;
            app.UITableRecs.ColumnEditable = true;
            app.srate = str2num(strrep(app.UITableRecs.Data.tdData{1}(1).sampleRate,'Hz','')); % assume sampling rate the same !!!
            
            % does mat exist?
            if ~app.UITable.Data.matExist(idxPlot) % mat doesn't exist, load it
                MAIN(app.UITable.Data.tdfile{idxPlot});
                [pn,fn, ext] = fileparts(app.UITable.Data.tdfile{idxPlot});
                load(fullfile(pn,[fn '.mat']));
                app.UITable.Data.matExist(idxPlot) = true;
                app.UITable.Data.fnm{idxPlot} = fullfile(pn,[fn '.mat']);
            else
                load(app.UITable.Data.fnm{idxPlot});
            end
            % get chanel names
            fldnms = outdatcomplete.Properties.VariableNames;
            idxuse = cellfun(@(x) any(strfind(x,'key')),outdatcomplete.Properties.VariableNames);
            chanms = fldnms(idxuse);
            x = outdatcomplete.derivedTimes;
            numplt = sum(idxuse);
            
            for c = 1:length(chanms)
                % plot raw data
                prnt = app.RawdataTab;
                app.hsb(1,c) = subplot(numplt,1,c,'Parent',prnt);
                %                 position = tmp.Position;
                %                 cla(tmp);
                %                 hsb(1,c) = axes('Parent',prnt,'Position',position);
                
                hplt  = plot(app.hsb(1,c),x,outdatcomplete.(chanms{c}));
                hplt.LineWidth = 1.5;
                
                %                 zoom(hsb(1,c),'xon');
                dat.x = x;
                dat.y = outdatcomplete.(chanms{c});
                set(app.hsb(1,c),'UserData',dat)
                % plot spectrogram
                prnt = app.SpectrogramTab;
                app.hsb(2,c)= subplot(numplt,1,c,'Parent',prnt);
                %                 position = tmp.Position;
                %                 cla(tmp);
                %                 hsb(2,c) = axes('Parent',prnt,'Position',position);
                frsuse = 1:1:ceil(app.srate/2);
                [s,f,t,p] = spectrogram(outdatcomplete.(chanms{c}),app.srate,ceil(0.8750*app.srate),frsuse,app.srate,...
                    'yaxis','power');
                dat.s = s;
                dat.f = f;
                dat.t = t;
                dat.p = p;
                set(app.hsb(2,c),'UserData',dat);
                
                axis(app.hsb(2,c),'tight');
                surf(app.hsb(2,c),seconds(t), f, 10*log10(p), 'EdgeColor', 'none');
                shading(app.hsb(2,c),'interp');
                view(app.hsb(2,c),2);
                xlabel(app.hsb(2,c),'seconds');
                ylabel(app.hsb(2,c),'Frequency (Hz)');
            end
            linkaxes(app.hsb(1,:),'x');
            linkaxes(app.hsb(2,:),'x');
            
        end

        % Cell edit callback: UITableRecs
        function setLimitsForRec(app, event)
            indices = event.Indices;
            newData = event.NewData;
            idxPlot = event.Indices(1,1);
            %% set limits raw data
            startTime = app.UITableRecs.Data.timeStart(idxPlot);
            endTime = app.UITableRecs.Data.timeEnd(idxPlot);
            set(app.hsb(1,1),'XLim',[startTime endTime]);
            for p = size(app.hsb,2)
                % set y limits for all
                dat = get(app.hsb(1,p),'UserData');
                x = dat.x;
                y = dat.y;
                idxuse = datenum(x) > datenum(startTime) & datenum(x) < datenum(endTime);
                ylims = [min(y(idxuse)) max(y(idxuse))];
                if ~isempty(ylims)
                    set(app.hsb(1,p),'YLim',ylims);
                end
            end
            
        end

        % Value changed function: PSDButton
        function PSDButtonValueChanged(app, event)
            value = app.PSDButton.Value;
            % punch out a psd window
            hfig = figure;
            %% set limits raw data
            idxPlot = find(app.UITableRecs.Data.plot == 1);
            startTime = app.UITableRecs.Data.timeStart(idxPlot);
            endTime = app.UITableRecs.Data.timeEnd(idxPlot);
            for p = 1:size(app.hsb,2)
                % set y limits for all
                dat = get(app.hsb(1,p),'UserData');
                
                x = dat.x;
                y = dat.y;
                x = x(ceil(length(x)/2):end);
                y = y(ceil(length(y)/2):end);
                
                idxuse = datenum(x) > datenum(startTime) & datenum(x) < datenum(endTime);
                xuse = x(idxuse);
                yuse = y(idxuse);
                [fftOut,f]   = pwelch(yuse,app.srate,app.srate/2,0:1:app.srate/2,app.srate,'psd');
                
                % take second half of data;
                hsb(p) = subplot(size(app.hsb,2),1,p,'Parent',hfig);
                hplt  = plot(hsb(p),f,log10(fftOut));
                hplt.LineWidth = 1.5;
                ylabel(hsb(p),'Power  (log_1_0\muV^2/Hz)');
                xlabel('Freuquency (Hz)');
                ttlstruc = app.UITableRecs.Data.tdData{idxPlot}(p);
                ttlstr = sprintf('e+ %d-%d LPF1%s LPF2%s sr = %d',...
                    ttlstruc.plusInput,ttlstruc.minusInput,ttlstruc.lpf1,ttlstruc.lpf2,ttlstruc.sampleRate);
                title(ttlstr); 
            end
            linkaxes(hsb,'xy');
        end

        % Value changed function: RawButton
        function RawButtonValueChanged(app, event)
            value = app.RawButton.Value;
            % punch out a psd window
            hfig = figure;
            %% set limits raw data
            idxPlot = find(app.UITableRecs.Data.plot == 1);
            startTime = app.UITableRecs.Data.timeStart(idxPlot);
            endTime = app.UITableRecs.Data.timeEnd(idxPlot);
            for p = 1:size(app.hsb,2)
                % set y limits for all
                dat = get(app.hsb(1,p),'UserData');
                
                x = dat.x;
                y = dat.y;
                
                idxuse = datenum(x) > datenum(startTime) & datenum(x) < datenum(endTime);
                xuse = x(idxuse);
                yuse = y(idxuse);


                hsb(p) = subplot(size(app.hsb,2),1,p,'Parent',hfig);
                hplt  = plot(hsb(p),seconds(xuse-xuse(1)),yuse);
                hplt.LineWidth = 1.5;
                ylabel(hsb(p),'Power');
                xlabel('Seconds');
                ttlstruc = app.UITableRecs.Data.tdData{idxPlot}(p);
                ttlstr = sprintf('e+ %d-%d LPF1%s LPF2%s sr = %d',...
                    ttlstruc.plusInput,ttlstruc.minusInput,ttlstruc.lpf1,ttlstruc.lpf2,ttlstruc.sampleRate);
                title(ttlstr); 
            end
            linkaxes(hsb,'xy');
        end

        % Value changed function: SpectrogramButton
        function SpectrogramButtonValueChanged(app, event)
             value = app.RawButton.Value;
            % punch out a spectral plot window 
            hfig = figure;
            %% set limits raw data
            idxPlot = find(app.UITableRecs.Data.plot == 1);
            startTime = app.UITableRecs.Data.timeStart(idxPlot);
            endTime = app.UITableRecs.Data.timeEnd(idxPlot);
            for p = 1:size(app.hsb,2)
                % set y limits for all
                dat = get(app.hsb(1,p),'UserData');
                
                x = dat.x;
                y = dat.y;
                
                idxuse = datenum(x) > datenum(startTime) & datenum(x) < datenum(endTime);
                xuse = x(idxuse);
                yuse = y(idxuse);


                hsb(p) = subplot(size(app.hsb,2),1,p,'Parent',hfig);
                hplt  = plot(hsb(p),seconds(x-x(1)),y);
                hplt.LineWidth = 1.5;
                ylabel(hsb(p),'Power');
                xlabel('Seconds');
                ttlstruc = app.UITableRecs.Data.tdData{idxPlot}(p);
                ttlstr = sprintf('e+ %d-%d LPF1%s LPF2%s sr = %d',...
                    ttlstruc.plusInput,ttlstruc.minusInput,ttlstruc.lpf1,ttlstruc.lpf2,ttlstruc.sampleRate);
                title(ttlstr); 
            end
            linkaxes(hsb,'xy');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FilesTab = uitab(app.TabGroup);
            app.FilesTab.Title = 'Files';
            app.UITable = uitable(app.FilesTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @plotRCSfile, true);
            app.loaddirButton = uibutton(app.FilesTab, 'push');
            app.loaddirButton.ButtonPushedFcn = createCallbackFcn(app, @loaddirButtonPushed, true);
            app.loaddirButton.Text = 'load dir';
            app.UITableRecs = uitable(app.FilesTab);
            app.UITableRecs.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableRecs.RowName = {};
            app.UITableRecs.CellEditCallback = createCallbackFcn(app, @setLimitsForRec, true);
            app.sessionsLabel = uilabel(app.FilesTab);
            app.sessionsLabel.Text = 'sessions';
            app.recordinginsessionsLabel = uilabel(app.FilesTab);
            app.recordinginsessionsLabel.Text = 'recording in sessions';
            app.RawdataTab = uitab(app.TabGroup);
            app.RawdataTab.Title = 'Raw data';
            app.PSDButton = uibutton(app.RawdataTab, 'state');
            app.PSDButton.ValueChangedFcn = createCallbackFcn(app, @PSDButtonValueChanged, true);
            app.PSDButton.Text = 'PSD';
            app.startEditFieldLabel = uilabel(app.RawdataTab);
            app.startEditFieldLabel.Text = 'start';
            app.startEditField = uieditfield(app.RawdataTab, 'numeric');
            app.endEditFieldLabel = uilabel(app.RawdataTab);
            app.endEditFieldLabel.Text = 'end';
            app.endEditField = uieditfield(app.RawdataTab, 'numeric');
            app.RawButton = uibutton(app.RawdataTab, 'state');
            app.RawButton.ValueChangedFcn = createCallbackFcn(app, @RawButtonValueChanged, true);
            app.RawButton.Text = 'Raw';
            app.SpectrogramButton = uibutton(app.RawdataTab, 'state');
            app.SpectrogramButton.ValueChangedFcn = createCallbackFcn(app, @SpectrogramButtonValueChanged, true);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.SpectrogramTab = uitab(app.TabGroup);
            app.SpectrogramTab.Title = 'Spectrogram';
        end
    end

    methods (Access = public)

        % Construct app
        function app = rcsViewer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end