classdef sense_stim_database_operations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        datachooseTab           matlab.ui.container.Tab
        sensesettingsPanel      matlab.ui.container.Panel
        chan1Label              matlab.ui.control.Label
        chan1ListBox            matlab.ui.control.ListBox
        chan2ListBoxLabel       matlab.ui.control.Label
        chan2ListBox            matlab.ui.control.ListBox
        chan3ListBoxLabel       matlab.ui.control.Label
        chan3ListBox            matlab.ui.control.ListBox
        chan4ListBoxLabel       matlab.ui.control.Label
        chan4ListBox            matlab.ui.control.ListBox
        minrecdurationminutesEditFieldLabel  matlab.ui.control.Label
        min_rec_dur             matlab.ui.control.NumericEditField
        stimsettingsPanel       matlab.ui.container.Panel
        stimListBoxLabel        matlab.ui.control.Label
        stimListBox             matlab.ui.control.ListBox
        groupListBoxLabel       matlab.ui.control.Label
        groupListBox            matlab.ui.control.ListBox
        rateListBoxLabel        matlab.ui.control.Label
        rateListBox             matlab.ui.control.ListBox
        currentListBoxLabel     matlab.ui.control.Label
        currentListBox          matlab.ui.control.ListBox
        contactListBoxLabel     matlab.ui.control.Label
        contactListBox          matlab.ui.control.ListBox
        QuantityofdataLabel     matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        loaddatabasefileButton  matlab.ui.control.Button
        printrawtabletocommandwindowButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        sense_stim_database_file % sense_stim data base file
        dataBaseTable % sense stim table
    end
    
    methods (Access = public)
        
        function results = populate_selectors(app)
            % populate all the tables 
            
            %% sense tab 
            for c = 1:4
                list_fn = sprintf('chan%dListBox',c);
                chanfn  = sprintf('chan%d',c);
                app.(list_fn).Items  = unique(app.dataBaseTable.(chanfn));
                app.(list_fn).Multiselect = 'on';
                app.(list_fn).Value  = unique(app.dataBaseTable.(chanfn));
            end
            %%
            
            %% stim tab
            
            % stim 
            app.stimListBox.Multiselect = 'on';
            app.stimListBox.Value = app.stimListBox.Items;
            
            % contact 
            list_fn = 'contactListBox';
            chanfn = 'electrodes';
            eleclist = unique(app.dataBaseTable.(chanfn));
            idxkeep = ~strcmp(eleclist,'NA'); 
            eleclist = eleclist(idxkeep);
            app.(list_fn).Items  = unique(eleclist);
            app.(list_fn).Multiselect = 'on';
            app.(list_fn).Value  = unique(eleclist);
            app.contactListBox
            
            % group
             list_fn = 'groupListBox';
            chanfn = 'group';
            eleclist = unique(app.dataBaseTable.(chanfn));
            idxkeep = ~strcmp(eleclist,'NA'); 
            eleclist = eleclist(idxkeep);
            app.(list_fn).Items  = unique(eleclist);
            app.(list_fn).Multiselect = 'on';
            app.(list_fn).Value  = unique(eleclist);
            
            % rate
            % each NaN is treated as a unique number 
            idxkeep = ~isnan(app.dataBaseTable.rate_Hz);
            rateslist = unique(app.dataBaseTable.rate_Hz(idxkeep));
            ratecell = {};
            for r = 1:length(rateslist)
                ratecell{r,1} = num2str(rateslist(r));
            end
            app.rateListBox.Items  = ratecell;
            app.rateListBox.Multiselect = 'on';
            app.rateListBox.Value  = ratecell;
            
            % amplitude
            % each NaN is treated as a unique number 
            idxkeep = ~isnan(app.dataBaseTable.amplitude_mA);
            amplist = unique(app.dataBaseTable.amplitude_mA(idxkeep));
            ampcell = {};
            for r = 1:length(amplist)
                ampcell{r,1} = num2str(amplist(r));
            end
            app.currentListBox.Items  = ampcell;
            app.currentListBox.Multiselect = 'on';
            app.currentListBox.Value  = ampcell;
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                app.sense_stim_database_file = varargin{1};
                load(app.sense_stim_database_file)
                app.dataBaseTable = sense_stim_table;
                app.populate_selectors();
            else
                app.dataBaseTable = [];
            end
        end

        % Button pushed function: loaddatabasefileButton
        function loaddatabasefileButtonPushed(app, event)
            [fn,pn] = uigetfile('*.mat','select .mat database file','MultiSelect','off');
            app.sense_stim_database_file = fullfile(pn,fn);
            load(app.sense_stim_database_file)
            app.dataBaseTable = sense_stim_table;
            app.populate_selectors();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.datachooseTab = uitab(app.TabGroup);
            app.datachooseTab.Title = 'data choose';
            app.sensesettingsPanel = uipanel(app.datachooseTab);
            app.sensesettingsPanel.Title = 'sense settings';
            app.chan1Label = uilabel(app.sensesettingsPanel);
            app.chan1Label.Text = 'chan 1';
            app.chan1ListBox = uilistbox(app.sensesettingsPanel);
            app.minrecdurationminutesEditFieldLabel = uilabel(app.sensesettingsPanel);
            app.minrecdurationminutesEditFieldLabel.Text = 'min rec duration (minutes)';
            app.min_rec_dur = uieditfield(app.sensesettingsPanel, 'numeric');
            app.min_rec_dur.Value = 10;
            app.chan2ListBoxLabel = uilabel(app.sensesettingsPanel);
            app.chan2ListBoxLabel.Text = 'chan 2';
            app.chan2ListBox = uilistbox(app.sensesettingsPanel);
            app.chan3ListBoxLabel = uilabel(app.sensesettingsPanel);
            app.chan3ListBoxLabel.Text = 'chan 3';
            app.chan3ListBox = uilistbox(app.sensesettingsPanel);
            app.chan4ListBoxLabel = uilabel(app.sensesettingsPanel);
            app.chan4ListBoxLabel.Text = 'chan 4';
            app.chan4ListBox = uilistbox(app.sensesettingsPanel);
            app.stimsettingsPanel = uipanel(app.datachooseTab);
            app.stimsettingsPanel.Title = 'stim settings';
            app.stimListBoxLabel = uilabel(app.stimsettingsPanel);
            app.stimListBoxLabel.Text = 'stim';
            app.stimListBox = uilistbox(app.stimsettingsPanel);
            app.stimListBox.Items = {'on', 'off'};
            app.stimListBox.Value = 'on';
            app.groupListBoxLabel = uilabel(app.stimsettingsPanel);
            app.groupListBoxLabel.Text = 'group';
            app.groupListBox = uilistbox(app.stimsettingsPanel);
            app.groupListBox.Items = {'A', 'B', 'C', 'D'};
            app.groupListBox.Value = 'A';
            app.rateListBoxLabel = uilabel(app.stimsettingsPanel);
            app.rateListBoxLabel.Text = 'rate';
            app.rateListBox = uilistbox(app.stimsettingsPanel);
            app.rateListBox.Items = {'130.2'};
            app.rateListBox.Value = '130.2';
            app.currentListBoxLabel = uilabel(app.stimsettingsPanel);
            app.currentListBoxLabel.Text = 'current';
            app.currentListBox = uilistbox(app.stimsettingsPanel);
            app.currentListBox.Items = {'5'};
            app.currentListBox.Value = '5';
            app.contactListBoxLabel = uilabel(app.stimsettingsPanel);
            app.contactListBoxLabel.Text = 'contact';
            app.contactListBox = uilistbox(app.stimsettingsPanel);
            app.contactListBox.Items = {'1-C+'};
            app.contactListBox.Value = '1-C+';
            app.QuantityofdataLabel = uilabel(app.datachooseTab);
            app.QuantityofdataLabel.Text = 'Quantity of data:';
            app.Label = uilabel(app.datachooseTab);
            app.Label.Text = '00:00:00';
            app.loaddatabasefileButton = uibutton(app.datachooseTab, 'push');
            app.loaddatabasefileButton.ButtonPushedFcn = createCallbackFcn(app, @loaddatabasefileButtonPushed, true);
            app.loaddatabasefileButton.Text = 'load database file';
            app.printrawtabletocommandwindowButton = uibutton(app.datachooseTab, 'push');
            app.printrawtabletocommandwindowButton.Text = 'print raw table to command window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sense_stim_database_operations(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end