classdef testApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        RCSPanel                matlab.ui.container.Panel
        RedrawButton            matlab.ui.control.Button
        FindPeaksButton         matlab.ui.control.Button
        channelDropDownLabel    matlab.ui.control.Label
        channelDropDown         matlab.ui.control.DropDown
        DelsysPanel             matlab.ui.container.Panel
        RedrawButton_2          matlab.ui.control.Button
        FindPeaksButton_2       matlab.ui.control.Button
        channelDropDown_2Label  matlab.ui.control.Label
        channelDropDown_2       matlab.ui.control.DropDown
        CenterPanel             matlab.ui.container.Panel
        UIAxes_1                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
        ComputePanel            matlab.ui.container.Panel
        ComputeButton           matlab.ui.control.Button
        checkButton             matlab.ui.control.Button
        DelsysAccDropDownLabel  matlab.ui.control.Label
        DelsysAccDropDown       matlab.ui.control.DropDown
        RCSSRLabel              matlab.ui.control.Label
        RCSsrOUT                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        hCurLine % handle to current line 
        hCurLineStatus % handle to current line stuats 
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
        
        function results = func3(app)
            
        end
    end
    
    methods (Access = public)
        
        function LineDown(app,event,src)
            app.hCurLine = event; 
            app.hCurLineStatus = 1; 
        end
        
        function MouseMove(app,event,src)
            if app.hCurLineStatus
                hUiaxes = get(app.hCurLine,'Parent');
                cp = get ( hUiaxes, 'CurrentPoint' );
                set ( app.hCurLine, 'YData', [cp(1,2) cp(1,2)] );

            end
        end
        
        function MouseUp(app,event,src)
            app.hCurLineStatus = 0; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            for i = 1:2
                ax = app.(sprintf('UIAxes_%d',i));
                hplt = plot(ax,[1 2],[2 2]);
                hold(ax,'on');
                hplt.ButtonDownFcn = @app.LineDown;
                hplt.LineWidth = 5;
                hplt = plot(ax,[1 2],[3 3]);
                hplt.ButtonDownFcn = @app.LineDown;
                hplt.LineWidth = 5;
                ylim(ax,[0 4]);
            end
            
            set(app.UIFigure,'WindowButtonMotion',@app.MouseMove);
            set(app.UIFigure,'WindowButtonUpFcn',@app.MouseUp);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {252, '1x', 135};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RCSPanel = uipanel(app.LeftPanel);
            app.RCSPanel.Title = 'RC+S';
            app.RedrawButton = uibutton(app.RCSPanel, 'push');
            app.RedrawButton.Text = 'Redraw';
            app.FindPeaksButton = uibutton(app.RCSPanel, 'push');
            app.FindPeaksButton.Text = 'Find Peaks';
            app.channelDropDownLabel = uilabel(app.RCSPanel);
            app.channelDropDownLabel.Text = 'channel';
            app.channelDropDown = uidropdown(app.RCSPanel);
            app.DelsysPanel = uipanel(app.LeftPanel);
            app.DelsysPanel.Title = 'Delsys';
            app.RedrawButton_2 = uibutton(app.DelsysPanel, 'push');
            app.RedrawButton_2.Text = 'Redraw';
            app.FindPeaksButton_2 = uibutton(app.DelsysPanel, 'push');
            app.FindPeaksButton_2.Text = 'Find Peaks';
            app.channelDropDown_2Label = uilabel(app.DelsysPanel);
            app.channelDropDown_2Label.Text = 'channel';
            app.channelDropDown_2 = uidropdown(app.DelsysPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes_1 = uiaxes(app.CenterPanel);
            title(app.UIAxes_1, 'RC+S ')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, 'Delsys')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.ComputePanel = uipanel(app.RightPanel);
            app.ComputePanel.Title = 'Compute';
            app.ComputeButton = uibutton(app.ComputePanel, 'push');
            app.ComputeButton.Text = 'Compute';
            app.checkButton = uibutton(app.ComputePanel, 'push');
            app.checkButton.Text = 'check';
            app.DelsysAccDropDownLabel = uilabel(app.ComputePanel);
            app.DelsysAccDropDownLabel.Text = 'Delsys Acc';
            app.DelsysAccDropDown = uidropdown(app.ComputePanel);
            app.RCSSRLabel = uilabel(app.ComputePanel);
            app.RCSSRLabel.Text = 'RC+S SR:';
            app.RCSsrOUT = uilabel(app.ComputePanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end