classdef videoPlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        videoTab                    matlab.ui.container.Tab
        SliderLabel                 matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        hVidAxes                    matlab.ui.control.UIAxes
        FileNameLabel               matlab.ui.control.Label
        hReportTime                 matlab.ui.control.Label
        startTimeVal                matlab.ui.control.Label
        starttimeLabel_2            matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        loadButton                  matlab.ui.control.Button
        playpauseButton             matlab.ui.control.Button
        frameBack                   matlab.ui.control.Button
        frameForward                matlab.ui.control.Button
        startMark                   matlab.ui.control.Button
        gotoEditFieldLabel          matlab.ui.control.Label
        goToSec                     matlab.ui.control.NumericEditField
        delsystouchTab              matlab.ui.container.Tab
        loadfileButton              matlab.ui.control.Button
        timeLabel                   matlab.ui.control.Label
        touchSlider                 matlab.ui.control.Slider
        hTouchAxes                  matlab.ui.control.UIAxes
        backButton                  matlab.ui.control.Button
        forwardButton               matlab.ui.control.Button
        selectchannelDropDownLabel  matlab.ui.control.Label
        selectchannelDropDown       matlab.ui.control.DropDown
        markstartButton             matlab.ui.control.Button
        winsizeEditFieldLabel       matlab.ui.control.Label
        winsizeEditField            matlab.ui.control.NumericEditField
        zoomoutButton               matlab.ui.control.Button
        zoomtoButton                matlab.ui.control.Button
        xminEditFieldLabel          matlab.ui.control.Label
        xminEditField               matlab.ui.control.NumericEditField
        xmaxEditFieldLabel          matlab.ui.control.Label
        xmaxEditField               matlab.ui.control.NumericEditField
        startLabel                  matlab.ui.control.Label
        delsyschannelselectTab      matlab.ui.container.Tab
        pcsTab                      matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        chanuseDropDownLabel        matlab.ui.control.Label
        chanuseDropDown             matlab.ui.control.DropDown
        loadBRButton                matlab.ui.control.Button
        timedomainEditFieldLabel    matlab.ui.control.Label
        timedomainEditField         matlab.ui.control.NumericEditField
        stimdomainEditFieldLabel    matlab.ui.control.Label
        stimdomainEditField         matlab.ui.control.NumericEditField
        powerdomainEditFieldLabel   matlab.ui.control.Label
        powerdomainEditField        matlab.ui.control.NumericEditField
        replayTab                   matlab.ui.container.Tab
    end

    
    properties (Access = public)
        filename % video file name
        pauseState % Description
        vidStartTime % Description
        delsysStartTime % Description
        delsysfilename % delsys file name
        delsysRaw % Description
        delTouchX % Delsys Touch sensor x (Seconds)
        delTouchY % Delsys Touch sensor y (values)
        hScatter  % handle for scatter element in delsys plot
        idxmiddle % middle idx for scatter delsys touch
        brdata % brain radio data
    end
    
    properties (Access = private)
        currentTime % Description
        vidReader % Description
    end
    
    methods (Access = private)
        
        function results = loadVidData(app)
            % clear current axis
            cla(app.hVidAxes);
            [pn,fn,ext] = fileparts(app.filename);
            % populate label
            app.FileNameLabel.Text = [fn '.' ext];
            % create a video reader object
            app.vidReader = VideoReader(app.filename);
            % format the slider
            app.Slider.Limits = [0 app.vidReader.Duration];
            app.Slider.MajorTicks = linspace(0,app.vidReader.Duration,10);
            app.currentTime = 0;
            % display the first frame
            img = readFrame(app.vidReader);
            currAxes = app.hVidAxes;
            image(app.hVidAxes,img);
            currAxes.Visible = 'off';
        end
        
    end
    
    methods (Access = public)
        
        function results = loadDelsysData(app)
            cla(app.hTouchAxes);
            % load delsys data
            app.delsysRaw = convertDelsysToMat(app.delsysfilename,'process');
            %
            chanNames = fieldnames(app.delsysRaw);
            chnlidx = cellfun(@(x) any(strfind(x,'IM')), chanNames);
            sratidx = cellfun(@(x) any(strfind(x,'srates')), chanNames);
            idxuse  = ~chnlidx & ~sratidx;
            % populate channel selector
            app.selectchannelDropDown.Items = chanNames(idxuse);
            % draw the sensor
            fnm = app.selectchannelDropDown.Value;
            app.delTouchY = app.delsysRaw.(fnm);
            srate = app.delsysRaw.srates.trig;
            app.delTouchX = seconds(1/srate:1/srate: (length(app.delTouchY))/srate);
            plot(app.delTouchX, app.delTouchY,'Parent',app.hTouchAxes);
            app.hTouchAxes.XLim = [min(app.delTouchX) max(app.delTouchX)];
            
            % set the scatter dot
            sliderX = app.hTouchAxes.XLim(2) / 2;
            app.idxmiddle = find(diff(app.delTouchX > sliderX)  == 1) + 1;
            hold(app.hTouchAxes,'on');
            app.hScatter = scatter(app.delTouchX(app.idxmiddle), app.delTouchY(app.idxmiddle),200,[0 0 0.9],...
                'Parent', app.hTouchAxes,...
                'MarkerFaceColor','none');
            
            % set the slider
            app.touchSlider.Limits = [0 length(app.delTouchY)/srate];
            app.touchSlider.MajorTicks = linspace(0,length(app.delTouchY)/srate,10);
            app.touchSlider.Value = seconds(app.delTouchX(app.idxmiddle));
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, settings)
            app.pauseState = 0;
            if exist('settings','var')
                if isfield(settings,'vidfilename')
                    app.filename = settings.vidfilename;
                    app.loadVidData();
                    [pn,fn ] = fileparts(app.filename);
                    if exist(fullfile(pn,'startTime.mat'),'file')
                        load(fullfile(pn,'startTime.mat')); 
                        
                    end
                end
                if isfield(settings,'delsysfilename')
                    app.delsysfilename = settings.delsysfilename;
                    app.loadDelsysData();
                end
                if isfield(settings,'pcsfilename')
                end
            end


        end

        % Button pushed function: loadButton
        function loadVidFile(app, event)
            [fn,pn] = uigetfile('*.mp4');
            app.filename = fullfile(pn,fn);
            app.loadVidData();
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.vidReader.CurrentTime = changingValue;
            % display the frame
            img = readFrame(app.vidReader);
            currAxes = app.hVidAxes;
            image(img, 'Parent', currAxes);
            currAxes.Visible = 'off';
            % display the current time
            app.hReportTime.Text = num2str(app.vidReader.CurrentTime);
            
        end

        % Button pushed function: playpauseButton
        function playpauseButtonPushed(app, event)
            if app.pauseState
                %                 while hasFrame(app.vidReader)
                %                     img = readFrame(app.vidReader);
                %                     currAxes = app.hVidAxes;
                %                     image(img, 'Parent', currAxes);
                %                     currAxes.Visible = 'off';
                %                 end
            end
            app.pauseState = ~app.pauseState;
            
        end

        % Button pushed function: frameForward
        function frameForwardButtonPushed(app, event)
            img = readFrame(app.vidReader);
            currAxes = app.hVidAxes;
            image(img, 'Parent', currAxes);
            currAxes.Visible = 'off';
            app.Slider.Value = app.vidReader.CurrentTime;
            app.hReportTime.Text = num2str(app.vidReader.CurrentTime);
        end

        % Button pushed function: frameBack
        function frameBackButtonPushed(app, event)
            timeuse = app.vidReader.CurrentTime - 1/app.vidReader.FrameRate;
            app.vidReader.CurrentTime = timeuse;
            % display the frame
            img = readFrame(app.vidReader);
            currAxes = app.hVidAxes;
            image(img, 'Parent', currAxes);
            currAxes.Visible = 'off';
        end

        % Value changed function: goToSec
        function goToSecValueChanged(app, event)
            app.vidReader.CurrentTime = app.goToSec.Value;
            % display the frame
            img = readFrame(app.vidReader);
            currAxes = app.hVidAxes;
            image(img, 'Parent', currAxes);
            currAxes.Visible = 'off';
        end

        % Button pushed function: startMark
        function startMarkButtonPushed(app, event)
            app.startTimeVal.Text = num2str(app.vidReader.CurrentTime);
            app.vidStartTime = app.vidReader.CurrentTime;
            [pn,fn ] = fileparts(app.filename);
            vidStartTime = app.vidStartTime;
            if exist(fullfile(pn,'startTime.mat'),'file')
                save(fullfile(pn,'startTime.mat'),'vidStartTime','-append');
            else
                save(fullfile(pn,'startTime.mat'),'vidStartTime');
            end
        end

        % Button pushed function: loadfileButton
        function loadDelsysFile(app, event)
            [fn,pn] = uigetfile('*.csv');
            app.delsysfilename = fullfile(pn,fn);
            app.loadDelsysData();
        end

        % Value changed function: touchSlider
        function touchSliderValueChanged(app, event)
            value = app.touchSlider.Value;
            
        end

        % Value changing function: touchSlider
        function touchSliderValueChanging(app, event)
            changingValue = event.Value;
            sliderX = changingValue;
            app.idxmiddle = find(diff(seconds(app.delTouchX) > sliderX)  == 1) + 1;
            app.hScatter.XData = app.delTouchX(app.idxmiddle);
            app.hScatter.YData = app.delTouchY(app.idxmiddle);
            app.xminEditField.Value = changingValue - app.winsizeEditField.Value;
            app.xmaxEditField.Value = changingValue + app.winsizeEditField.Value;
            app.startLabel.Text = num2str(changingValue);
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            app.idxmiddle = app.idxmiddle -1;
            app.hScatter.XData = app.delTouchX(app.idxmiddle);
            app.hScatter.YData = app.delTouchY(app.idxmiddle);
        end

        % Button pushed function: forwardButton
        function forwardButtonPushed(app, event)
            app.idxmiddle = app.idxmiddle + 1;
            app.hScatter.XData = app.delTouchX(app.idxmiddle);
            app.hScatter.YData = app.delTouchY(app.idxmiddle);
        end

        % Value changed function: winsizeEditField
        function winsizeEditFieldValueChanged(app, event)
            value = app.winsizeEditField.Value;
            idx = app.idxmiddle;
            secswin = seconds(value/2);
            xlimuse = [app.delTouchX(idx)-secswin app.delTouchX(idx)+secswin];
            app.hTouchAxes.XLim = xlimuse;
        end

        % Button pushed function: zoomoutButton
        function zoomoutButtonPushed(app, event)
            app.hTouchAxes.XLim = [seconds(0) max(app.delTouchX)];
        end

        % Button pushed function: zoomtoButton
        function zoomtoButtonPushed(app, event)
            app.hTouchAxes.XLim = seconds([app.xminEditField.Value app.xmaxEditField.Value]);
        end

        % Button pushed function: markstartButton
        function markstartButtonPushed(app, event)
            app.delsysStartTime = app.touchSlider.Value;
            [pn,fn ] = fileparts(app.filename);
            delsysStartTime = app.delsysStartTime;
            if exist(fullfile(pn,'startTime.mat'),'file')
                save(fullfile(pn,'startTime.mat'),'delsysStartTime','-append');
            else
                save(fullfile(pn,'startTime.mat'),'delsysStartTime');
            end
        end

        % Button pushed function: loadBRButton
        function loadBRButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.videoTab = uitab(app.TabGroup);
            app.videoTab.Title = 'video';
            app.SliderLabel = uilabel(app.videoTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.videoTab);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.hVidAxes = uiaxes(app.videoTab);
            title(app.hVidAxes, 'Title')
            xlabel(app.hVidAxes, 'X')
            ylabel(app.hVidAxes, 'Y')
            app.FileNameLabel = uilabel(app.videoTab);
            app.FileNameLabel.Text = 'FileName';
            app.hReportTime = uilabel(app.videoTab);
            app.hReportTime.Text = 'Time';
            app.startTimeVal = uilabel(app.videoTab);
            app.startTimeVal.Text = 'val';
            app.starttimeLabel_2 = uilabel(app.videoTab);
            app.starttimeLabel_2.Text = 'start time:';
            app.Panel = uipanel(app.videoTab);
            app.Panel.Title = 'Panel';
            app.loadButton = uibutton(app.Panel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadVidFile, true);
            app.loadButton.Text = 'load';
            app.playpauseButton = uibutton(app.Panel, 'push');
            app.playpauseButton.ButtonPushedFcn = createCallbackFcn(app, @playpauseButtonPushed, true);
            app.playpauseButton.Text = 'play/pause';
            app.frameBack = uibutton(app.Panel, 'push');
            app.frameBack.ButtonPushedFcn = createCallbackFcn(app, @frameBackButtonPushed, true);
            app.frameBack.Text = '< f';
            app.frameForward = uibutton(app.Panel, 'push');
            app.frameForward.ButtonPushedFcn = createCallbackFcn(app, @frameForwardButtonPushed, true);
            app.frameForward.Text = 'f >';
            app.startMark = uibutton(app.Panel, 'push');
            app.startMark.ButtonPushedFcn = createCallbackFcn(app, @startMarkButtonPushed, true);
            app.startMark.Text = 'mark start';
            app.gotoEditFieldLabel = uilabel(app.Panel);
            app.gotoEditFieldLabel.Text = 'go to';
            app.goToSec = uieditfield(app.Panel, 'numeric');
            app.goToSec.ValueChangedFcn = createCallbackFcn(app, @goToSecValueChanged, true);
            app.delsystouchTab = uitab(app.TabGroup);
            app.delsystouchTab.Title = 'delsys touch';
            app.loadfileButton = uibutton(app.delsystouchTab, 'push');
            app.loadfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadDelsysFile, true);
            app.loadfileButton.Text = 'load file';
            app.timeLabel = uilabel(app.delsystouchTab);
            app.timeLabel.Text = 'time';
            app.touchSlider = uislider(app.delsystouchTab);
            app.touchSlider.ValueChangedFcn = createCallbackFcn(app, @touchSliderValueChanged, true);
            app.touchSlider.ValueChangingFcn = createCallbackFcn(app, @touchSliderValueChanging, true);
            app.hTouchAxes = uiaxes(app.delsystouchTab);
            title(app.hTouchAxes, 'Title')
            xlabel(app.hTouchAxes, 'X')
            ylabel(app.hTouchAxes, 'Y')
            app.backButton = uibutton(app.delsystouchTab, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = '< back';
            app.forwardButton = uibutton(app.delsystouchTab, 'push');
            app.forwardButton.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forwardButton.Text = 'forward >';
            app.selectchannelDropDownLabel = uilabel(app.delsystouchTab);
            app.selectchannelDropDownLabel.Text = 'select channel ';
            app.selectchannelDropDown = uidropdown(app.delsystouchTab);
            app.markstartButton = uibutton(app.delsystouchTab, 'push');
            app.markstartButton.ButtonPushedFcn = createCallbackFcn(app, @markstartButtonPushed, true);
            app.markstartButton.Text = 'mark start';
            app.winsizeEditFieldLabel = uilabel(app.delsystouchTab);
            app.winsizeEditFieldLabel.Text = 'win size';
            app.winsizeEditField = uieditfield(app.delsystouchTab, 'numeric');
            app.winsizeEditField.ValueChangedFcn = createCallbackFcn(app, @winsizeEditFieldValueChanged, true);
            app.winsizeEditField.Value = 50;
            app.zoomoutButton = uibutton(app.delsystouchTab, 'push');
            app.zoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @zoomoutButtonPushed, true);
            app.zoomoutButton.Text = 'zoom out';
            app.zoomtoButton = uibutton(app.delsystouchTab, 'push');
            app.zoomtoButton.ButtonPushedFcn = createCallbackFcn(app, @zoomtoButtonPushed, true);
            app.zoomtoButton.Text = 'zoom to';
            app.xminEditFieldLabel = uilabel(app.delsystouchTab);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.delsystouchTab, 'numeric');
            app.xmaxEditFieldLabel = uilabel(app.delsystouchTab);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.delsystouchTab, 'numeric');
            app.startLabel = uilabel(app.delsystouchTab);
            app.delsyschannelselectTab = uitab(app.TabGroup);
            app.delsyschannelselectTab.Title = 'delsys channel select';
            app.pcsTab = uitab(app.TabGroup);
            app.pcsTab.Title = 'pc+s';
            app.UIAxes = uiaxes(app.pcsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.chanuseDropDownLabel = uilabel(app.pcsTab);
            app.chanuseDropDownLabel.Text = 'chan use';
            app.chanuseDropDown = uidropdown(app.pcsTab);
            app.loadBRButton = uibutton(app.pcsTab, 'push');
            app.loadBRButton.ButtonPushedFcn = createCallbackFcn(app, @loadBRButtonPushed, true);
            app.loadBRButton.Text = 'load BR';
            app.timedomainEditFieldLabel = uilabel(app.pcsTab);
            app.timedomainEditFieldLabel.Text = 'time domain';
            app.timedomainEditField = uieditfield(app.pcsTab, 'numeric');
            app.stimdomainEditFieldLabel = uilabel(app.pcsTab);
            app.stimdomainEditFieldLabel.Text = 'stim domain';
            app.stimdomainEditField = uieditfield(app.pcsTab, 'numeric');
            app.powerdomainEditFieldLabel = uilabel(app.pcsTab);
            app.powerdomainEditFieldLabel.Text = 'power domain';
            app.powerdomainEditField = uieditfield(app.pcsTab, 'numeric');
            app.replayTab = uitab(app.TabGroup);
            app.replayTab.Title = 'replay';
        end
    end

    methods (Access = public)

        % Construct app
        function app = videoPlayer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end